classdef SubZero_ROS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Menu                           matlab.ui.container.Menu
        ARKMenu                        matlab.ui.container.Menu
        RestartMenu                    matlab.ui.container.Menu
        ShutdownMenu                   matlab.ui.container.Menu
        EXITMenu                       matlab.ui.container.Menu
        RadioStreamBox                 matlab.ui.control.TextArea
        SendCMDButton                  matlab.ui.control.Button
        SendCMDBox                     matlab.ui.control.EditField
        TabGroup                       matlab.ui.container.TabGroup
        RoverSettingTab                matlab.ui.container.Tab
        SettingsQueryButton            matlab.ui.control.Button
        Panel_10                       matlab.ui.container.Panel
        IgnoreInstrumentSwitch         matlab.ui.control.ToggleSwitch
        IgnoreInstrumentSwitchLabel    matlab.ui.control.Label
        LowBatteryModeSwitch           matlab.ui.control.ToggleSwitch
        LowBatteryModeSwitchLabel      matlab.ui.control.Label
        Panel_9                        matlab.ui.container.Panel
        HandBreakSwitch_1              matlab.ui.control.ToggleSwitch
        HandBreakLabel                 matlab.ui.control.Label
        Panel_11                       matlab.ui.container.Panel
        RADIOSwitch                    matlab.ui.control.ToggleSwitch
        RADIOSwitchLabel               matlab.ui.control.Label
        RadioPortDropDown              matlab.ui.control.DropDown
        RoverParametersPanel           matlab.ui.container.Panel
        RoverPositionErrorEditField    matlab.ui.control.NumericEditField
        RoverPositionErrorEditFieldLabel  matlab.ui.control.Label
        WOffsetEditField               matlab.ui.control.NumericEditField
        WOffsetEditFieldLabel          matlab.ui.control.Label
        WFactorEditField               matlab.ui.control.NumericEditField
        WFactorEditFieldLabel          matlab.ui.control.Label
        ICoefficientEditField          matlab.ui.control.NumericEditField
        ICoefficientEditFieldLabel     matlab.ui.control.Label
        PCoefficientEditField          matlab.ui.control.NumericEditField
        PCoefficientEditFieldLabel     matlab.ui.control.Label
        WaypointRadiusmEditField       matlab.ui.control.NumericEditField
        WaypointRadiusmEditFieldLabel  matlab.ui.control.Label
        NavigationRadiusmEditField     matlab.ui.control.NumericEditField
        NavigationRadiusmEditFieldLabel  matlab.ui.control.Label
        SARMaxSpeedEditField           matlab.ui.control.NumericEditField
        SARMaxSpeedLabel               matlab.ui.control.Label
        VFactorEditField               matlab.ui.control.NumericEditField
        VFactorEditField_2Label        matlab.ui.control.Label
        SpeedPCoeffEditField           matlab.ui.control.NumericEditField
        SpeedPCoeffEditFieldLabel      matlab.ui.control.Label
        AutoMaxSpeedEditField          matlab.ui.control.NumericEditField
        AutoMaxSpeedLabel              matlab.ui.control.Label
        TelemetryIntervalsEditField    matlab.ui.control.NumericEditField
        TelemetryIntervalsLabel        matlab.ui.control.Label
        ARK_PowerPushed                matlab.ui.control.Button
        EXITButtonPushed               matlab.ui.control.Button
        DriverScreenTab                matlab.ui.container.Tab
        vltBR                          matlab.ui.control.NumericEditField
        vltFR                          matlab.ui.control.NumericEditField
        vltBL                          matlab.ui.control.NumericEditField
        vltFL                          matlab.ui.control.NumericEditField
        StraightButton2                matlab.ui.control.Button
        STOPButton                     matlab.ui.control.Button
        Right20Button                  matlab.ui.control.Button
        Left20Button                   matlab.ui.control.Button
        SlowDown10Button               matlab.ui.control.Button
        SpeedUp10Button                matlab.ui.control.Button
        Panel_4                        matlab.ui.container.Panel
        StraightButton                 matlab.ui.control.Button
        WSlider                        matlab.ui.control.Slider
        Panel_3                        matlab.ui.container.Panel
        VSlider                        matlab.ui.control.Slider
        Panel_2                        matlab.ui.container.Panel
        ComeHomeSwitch                 matlab.ui.control.ToggleSwitch
        ComeHomeSwitch_2Label          matlab.ui.control.Label
        HandBreakSwitch_2              matlab.ui.control.ToggleSwitch
        HandBreakSwitch_2Label         matlab.ui.control.Label
        ReverseSwitch                  matlab.ui.control.ToggleSwitch
        ReverseSwitchLabel             matlab.ui.control.Label
        UIax_ManualDriveMap            matlab.ui.control.UIAxes
        AutoDriveTab                   matlab.ui.container.Tab
        RefDstEditField                matlab.ui.control.NumericEditField
        RefDstEditFieldLabel           matlab.ui.control.Label
        nCurrentPoint                  matlab.ui.control.NumericEditField
        CurrentpointLabel              matlab.ui.control.Label
        LP2HEdit_2                     matlab.ui.control.NumericEditField
        MaxD2HLabel_2                  matlab.ui.control.Label
        SurveyKMEdit_2                 matlab.ui.control.NumericEditField
        SurveykmLabel_2                matlab.ui.control.Label
        NsurveyPoints_2                matlab.ui.control.NumericEditField
        PointsEditFieldLabel_2         matlab.ui.control.Label
        B2GoalEditField                matlab.ui.control.NumericEditField
        B2GoalEditFieldLabel           matlab.ui.control.Label
        Panel_6                        matlab.ui.container.Panel
        UIax_SurveyMap                 matlab.ui.control.UIAxes
        D2GoalEditField                matlab.ui.control.NumericEditField
        D2GoalEditFieldLabel           matlab.ui.control.Label
        YGoalEditField                 matlab.ui.control.NumericEditField
        YGoalEditFieldLabel            matlab.ui.control.Label
        XGoalEditField                 matlab.ui.control.NumericEditField
        XGoalEditFieldLabel            matlab.ui.control.Label
        Y_RoverNow_UTM                 matlab.ui.control.NumericEditField
        YNowLabel                      matlab.ui.control.Label
        X_RoverNow_UTM                 matlab.ui.control.NumericEditField
        XNowLabel                      matlab.ui.control.Label
        DesignasurveyTab               matlab.ui.container.Tab
        StartHereCheckBox              matlab.ui.control.CheckBox
        UpdateFigureButton             matlab.ui.control.Button
        SurveyModeDesign               matlab.ui.container.ButtonGroup
        PointButton                    matlab.ui.control.RadioButton
        AreaButton                     matlab.ui.control.RadioButton
        ProfileButton                  matlab.ui.control.RadioButton
        SurveyTypeDesign               matlab.ui.container.ButtonGroup
        SARButton                      matlab.ui.control.RadioButton
        PolarimetricButton             matlab.ui.control.RadioButton
        AdventureButton                matlab.ui.control.RadioButton
        InputFormat                    matlab.ui.control.DropDown
        LP2HEdit                       matlab.ui.control.NumericEditField
        MaxD2HLabel                    matlab.ui.control.Label
        SurveyKMEdit                   matlab.ui.control.NumericEditField
        SurveykmLabel                  matlab.ui.control.Label
        NsurveyPoints                  matlab.ui.control.NumericEditField
        PointsEditFieldLabel           matlab.ui.control.Label
        SAVEButton                     matlab.ui.control.Button
        ClearButton                    matlab.ui.control.StateButton
        FirstpointPanel                matlab.ui.container.Panel
        LableLon2                      matlab.ui.control.Label
        LableLat2                      matlab.ui.control.Label
        LabledY                        matlab.ui.control.Label
        LabledX                        matlab.ui.control.Label
        YmEditSpacing                  matlab.ui.control.NumericEditField
        XmEditSpacing                  matlab.ui.control.NumericEditField
        LatEditLast                    matlab.ui.control.NumericEditField
        LonEditLast                    matlab.ui.control.NumericEditField
        LableLon1                      matlab.ui.control.Label
        LableLat1                      matlab.ui.control.Label
        LonEditFirst                   matlab.ui.control.NumericEditField
        LatEditFirst                   matlab.ui.control.NumericEditField
        LoadButton                     matlab.ui.control.Button
        SENDTOARKButton                matlab.ui.control.Button
        SimulateButton                 matlab.ui.control.StateButton
        SimAx                          matlab.ui.control.UIAxes
        RadarTab                       matlab.ui.container.Tab
        FileNameOptionalTimeandLocationDATwillbeaddedtothisnamePanel  matlab.ui.container.Panel
        ApRESPreFixName                matlab.ui.control.EditField
        DownloadtoARKCheckBox          matlab.ui.control.CheckBox
        ApRESPanel                     matlab.ui.container.Panel
        ApRESSwitch                    matlab.ui.control.ToggleSwitch
        SubBurstsPanel                 matlab.ui.container.Panel
        testoffButton                  matlab.ui.control.Button
        testonButton                   matlab.ui.control.Button
        nSubbursts                     matlab.ui.control.NumericEditField
        NumberofSubBurstsEditFieldLabel  matlab.ui.control.Label
        SETApRES                       matlab.ui.control.StateButton
        AttenuatorSettingPanel         matlab.ui.container.Panel
        Att4                           matlab.ui.control.NumericEditField
        Gain4                          matlab.ui.control.NumericEditField
        Gain1                          matlab.ui.control.NumericEditField
        GainValues1446Label            matlab.ui.control.Label
        Att1                           matlab.ui.control.NumericEditField
        AttenuatorValues031Label       matlab.ui.control.Label
        nAtts                          matlab.ui.control.NumericEditField
        NumberofAttenuatorEditFieldLabel  matlab.ui.control.Label
        Gain3                          matlab.ui.control.NumericEditField
        Gain2                          matlab.ui.control.NumericEditField
        Att3                           matlab.ui.control.NumericEditField
        Att2                           matlab.ui.control.NumericEditField
        BURSTApRES                     matlab.ui.control.StateButton
        AntennaPortOrientationPanel    matlab.ui.container.Panel
        Rx2Port                        matlab.ui.control.NumericEditField
        Rx2EditFieldLabel              matlab.ui.control.Label
        Tx2Port                        matlab.ui.control.NumericEditField
        Tx2EditFieldLabel              matlab.ui.control.Label
        Rx1Port                        matlab.ui.control.NumericEditField
        Rx1EditFieldLabel              matlab.ui.control.Label
        Tx1Port                        matlab.ui.control.NumericEditField
        Tx1EditFieldLabel              matlab.ui.control.Label
        Rx2Pol                         matlab.ui.control.EditField
        Tx2Pol                         matlab.ui.control.EditField
        Rx1Pol                         matlab.ui.control.EditField
        Tx1Pol                         matlab.ui.control.EditField
        RadarModeButtonGroup           matlab.ui.container.ButtonGroup
        MIMOButton                     matlab.ui.control.RadioButton
        MONOButton                     matlab.ui.control.RadioButton
        General                        matlab.ui.container.Panel
        Panel_12                       matlab.ui.container.Panel
        DriveModeSwitch                matlab.ui.control.Switch
        LBMLamp                        matlab.ui.control.Lamp
        LBMLampLabel                   matlab.ui.control.Label
        APilotLamp                     matlab.ui.control.Lamp
        APilotLampLabel                matlab.ui.control.Label
        ApRESLamp                      matlab.ui.control.Lamp
        ApRESLampLabel                 matlab.ui.control.Label
        IgnoreInstLamp                 matlab.ui.control.Lamp
        IgnoreInstLampLabel            matlab.ui.control.Label
        HandBreakLamp                  matlab.ui.control.Lamp
        HandBreakLampLabel             matlab.ui.control.Label
        RadioLamp                      matlab.ui.control.Lamp
        RadioLampLabel                 matlab.ui.control.Label
        ErrorLamp                      matlab.ui.control.Lamp
        ErrorLampLabel                 matlab.ui.control.Label
        QuickBurstButton               matlab.ui.control.Button
        SwitchGPSButton                matlab.ui.control.Button
        UpdateHomeButton               matlab.ui.control.Button
        Panel_8                        matlab.ui.container.Panel
        BatteryLeftH                   matlab.ui.control.NumericEditField
        GPSspeedValue                  matlab.ui.control.NumericEditField
        BatteryVGauge                  matlab.ui.control.Gauge
        BatteryVGaugeLabel             matlab.ui.control.Label
        GPSSpeedmsGauge                matlab.ui.control.SemicircularGauge
        GPSSpeedmsGaugeLabel           matlab.ui.control.Label
        BatteryLamp                    matlab.ui.control.Lamp
        velANG                         matlab.ui.control.NumericEditField
        velLIN                         matlab.ui.control.NumericEditField
        RoverNowText                   matlab.ui.control.TextArea
        EMERGENCYSTOPButton            matlab.ui.control.Button
    end

    
    properties (Access = private)
        % Radio
        RadioTxt
        RadioDevice
        RadioConnectionFlag
        RadioBaudRate
        RadioTimeOut
        RadioString
        % GPS
        E % ellipsoid
        WhichGPS
        gpsqual
        GPSQuality
        gpsAge
        gpsTime
        RoverLatDD
        RoverLngDD
        xRover
        yRover
        RoverTail
        Altitude
        Course
        % Home
        HomeLatDD
        HomeLonDD
        xHome
        yHome
        D2H % distance to home
        % Battery
        BatUsg
        TicBattery
        % tic/toc
        ticRadio
        ticTelemetry
        % Const
        vDir
        vInc
        wInc
        TurnRadius
        PossibleW
        L
        %% Plots
        Plt_HomeAtPilotMap % plot Home position in the manual map
        Plt_RoverAtPilotMap % plot Home position in the manual map
        Plt_RoverTailAtPilotMap
        Plt_HomeAtSurveyMap % plot Rover position in the manual map
        Plt_RoverAtSurveyMap % plot Rover position in the manual map
        Plt_RoverTailAtSurveyMap
        %% Survey
        X1st
        Y1st
        Xgt
        Xlast
        Ylast
        Ygt
        utmZ1
        utmZ2
        utmgt
        GT_Points
        SurveyPoints
        nSuveyPoints
        SurveyPointsMSG
        SurveyPointsInARK
        % Flags
        ExitMainLoopFlag
        ExitAppFlag % flag to exit to app correctly
        MainLoopRunningFlag
    end
    
    methods (Access = private)
        %-----------------------------------------------------
        function [] = UpdateRadioStream(app,newtxt)
            if ~contains(newtxt,'ping')
                newtxt = string(datetime('now','Format','HH:mm:ss --> '))+newtxt;
                app.RadioTxt = [newtxt,app.RadioTxt];
                app.RadioStreamBox.Value = app.RadioTxt;
            end
        end 
        %-----------------------------------------------------
        function [] = CreateRadioDeviceObj(app)
            app.RadioDevice = [];
            RadioPort = string(app.RadioPortDropDown.Value);
            try
                app.RadioDevice = serialport(RadioPort,app.RadioBaudRate,"Timeout",app.RadioTimeOut);
                configureTerminator(app.RadioDevice,"CR");
                newtxt = "Radio Device Created :)";
                UpdateRadioStream(app,newtxt)
                app.RADIOSwitch.Enable = 1;
            catch
                app.RadioDevice = [];
                newtxt = "Wrong Port :)";
                UpdateRadioStream(app,newtxt)  
                app.RADIOSwitch.Enable = 0;
            end
        end
        %-----------------------------------------------------
        function [] = RadioUpdate(app)
            try
                dev = app.RadioDevice;
                NBA = dev.NumBytesAvailable;
                if NBA ~= 0
                    app.ticRadio = tic;
                    app.RadioConnectionFlag = true;
                    NewRadioString = readline(dev);
                    flush(dev,"input")
                    if contains(NewRadioString,"#")
                        try
                            NewRadioString = char(NewRadioString);
                            i_in = find(NewRadioString=='#');
                            i_te = find(NewRadioString=='$');
                            NewRadioString = NewRadioString(i_in:i_te);
                            NewRadioString = erase(NewRadioString,"#");
                            NewRadioString = erase(NewRadioString,"$");
                            app.RadioString = string(NewRadioString);
                            % Read the Radio msg and understand it
                            AnalyzeRadioString(app);
                        catch ME
                            app.RadioConnectionFlag = false;
                            newtxt = "*ERROR* Can Not Understand The Radio Message :(";
                            UpdateRadioStream(app,newtxt)
                            newtxt = ME.message;
                            UpdateRadioStream(app,newtxt)
                            if app.ErrorLamp.Enable == 0
                                app.ErrorLamp.Enable = 1;
                            end
                        end
                    end
                    if app.ErrorLamp.Enable == 1
                        app.ErrorLamp.Enable = 0;
                    end
                    newtxt = string(NewRadioString);
                    UpdateRadioStream(app,newtxt)
                else
                    if toc(app.ticRadio) > app.TelemetryIntervalsEditField.Value+2
                        app.ticRadio = tic;
                        app.RadioConnectionFlag = false;
                        newtxt = "*ERROR* No Message From The Rover :(";
                        UpdateRadioStream(app,newtxt)
                        if app.ErrorLamp.Enable == 0
                            app.ErrorLamp.Enable = 1;
                        end
                    end
                end
                RadioConnectionLight(app);
            catch ME
                newtxt = "*ERROR* in RadioUpdate";
                UpdateRadioStream(app,newtxt)
                newtxt = ME.message;
                UpdateRadioStream(app,newtxt) 
            end
        end
        %-----------------------------------------------------
        function [] = AnalyzeRadioString(app)
            tel = app.RadioString;
%             if tel == "ping"
%                 sendCMD(app,char("#pong$"));
            if contains(tel,"TELEMETRY")
                app.ticTelemetry = tic;
                TelemetryMsg(app);
                if app.DriveModeSwitch.Value == "Manual" 
                    sendCMD(app,char("#pong$"));
                end
            elseif tel == "Survey: Done"
                app.DriveModeSwitch.Value = "Manual";
                DriveModeSwitchValueChanged(app)
            elseif contains(tel,"ApRES")
                ApRES_Status(app);
            elseif contains(tel,"HomeUpdated")
                HomeUpdated(app);
            elseif contains(tel,"I am Home")
                app.ComeHomeSwitch.Value = "Off";
                app.DriveModeSwitch.Value = "Manual";
            elseif tel == "Garmin is Active"
                app.WhichGPS = "Garmin";
            elseif tel == "Trimble is Active"
                app.WhichGPS = "Trimble";
            end
        end
        %-----------------------------------------------------
        function HomeUpdated(app)
            tel = app.RadioString;
            tel = erase(tel,'HomeUpdated,');
            sepTEL = strsplit(tel,',');
            app.HomeLatDD = str2double(sepTEL(1)); % lat Home dd
            app.HomeLonDD = str2double(sepTEL(2)); % lon Home dd
            [app.xHome,app.yHome] = deg2utm(app,app.HomeLatDD,app.HomeLonDD);
            try
                app.D2H = sqrt(((app.xHome-app.xRover).^2)+((app.yHome-app.yRover).^2));
            catch
                app.D2H = "NaN";
            end
            RoverAndHomePlot(app);
            UpdateRoverNowText(app)
        end
        %-----------------------------------------------------
        function ApRES_Status(app)
            tel = app.RadioString;
            if tel == "ApRES: API created"
                app.SETApRES.Enable = 1;
                app.ApRESLamp.Color = [0.00,1.00,0.00];
            elseif tel == "ApRES: Config updated"
                app.BURSTApRES.Enable = 1;
                app.QuickBurstButton.Enable = 1;
            elseif tel == "ApRES: Bursting"
                app.SETApRES.Enable = 0;
                app.BURSTApRES.Enable = 0;
                app.QuickBurstButton.Enable = 0;
                app.ApRESLamp.Color = [0.07,0.62,1.00];
            elseif tel == "ApRES: Done"
                app.SETApRES.Enable = 1;
                app.BURSTApRES.Enable = 1;
                app.QuickBurstButton.Enable = 1;
                app.ApRESLamp.Color = [1.00,1.00,1.00];
            elseif tel == "ApRES: Failed"
                app.SETApRES.Enable = 1;
                app.BURSTApRES.Enable = 1;
                app.QuickBurstButton.Enable = 1;
                app.ApRESLamp.Color = [1.00,0.00,0.00]; 
            end
        end
        %-----------------------------------------------------
        function TelemetryMsg(app)
            tel = app.RadioString;
            tel = erase(tel,'TELEMETRY,');
            sepTEL = strsplit(tel,',');
            % GPS ----
            TEL_Age = sepTEL(1); % gps age
            TEL_Time = sepTEL(2); % gps time (utc)
            TEL_Lat = sepTEL(3); % gps latitude (signed dd)
            TEL_Lon = sepTEL(4); % gps longitude (signed dd)
            TEL_Alt = sepTEL(5); % gps altitude (msl)
            TEL_Spd = sepTEL(6); % gps speed (m/s)
            TEL_Crs = sepTEL(7); % gps course (deg)
            % Battery ----
            TEL_BatVlt = sepTEL(8); % Rover's battery voltage (v)
            % Velocity ----
            TEL_V = sepTEL(9); % Rover's linear velocity (-100:100)
            TEL_W = sepTEL(10); % Rover's angular velocity (-140:140)
            % Destination ----
            TEL_Npnts = sepTEL(11);
            TEL_NxtLat = sepTEL(12);
            TEL_NxtLon = sepTEL(13);
            TEL_Br2Gl = sepTEL(14);
            TEL_Dst2Gl = sepTEL(15);
            TEL_DstRef = sepTEL(16);
            TEL_iQual = str2double(sepTEL(17))+1;
            % ----
            app.gpsAge = TEL_Age;
            app.gpsTime = TEL_Time;
            app.Altitude = TEL_Alt;
            app.Course = TEL_Crs;
            app.RoverLatDD = str2double(TEL_Lat);
            app.RoverLngDD = str2double(TEL_Lon);
            [app.xRover,app.yRover] = deg2utm(app,app.RoverLatDD,app.RoverLngDD);
            app.X_RoverNow_UTM.Value = app.xRover./1000;
            app.Y_RoverNow_UTM.Value = app.yRover./1000;
            app.GPSSpeedmsGauge.Value = str2double(TEL_Spd);
            app.GPSspeedValue.Value = str2double(TEL_Spd);
            if TEL_iQual == 10
                app.GPSQuality = "RMC";
            else
               app.GPSQuality = app.gpsqual(TEL_iQual); 
            end
            % ----
            BatteryUsage(app,str2double(TEL_BatVlt));
            app.BatteryVGauge.Value = str2double(TEL_BatVlt);
            BatteryColor(app)
            % ----
            app.velLIN.Value = str2double(TEL_V);
            app.velANG.Value = str2double(TEL_W);
            % ----
            if app.DriveModeSwitch.Value == "Auto"
                app.nCurrentPoint.Value = str2double(TEL_Npnts);
            end
            if str2double(TEL_NxtLat) == 0 && str2double(TEL_NxtLon) == 0
                app.XGoalEditField.Value = 0;
                app.YGoalEditField.Value = 0;
                app.B2GoalEditField.Value = 0;
                app.D2GoalEditField.Value = 0;
            else
                [XGoal,YGoal] = deg2utm(app,str2double(TEL_NxtLat),str2double(TEL_NxtLon));
                app.XGoalEditField.Value = XGoal./1000;
                app.YGoalEditField.Value = YGoal./1000;
                app.D2GoalEditField.Value = str2double(TEL_Dst2Gl);
                app.B2GoalEditField.Value = str2double(TEL_Br2Gl);
                app.RefDstEditField.Value = str2double(TEL_DstRef);
            end
            % ----
            try
                app.D2H = sqrt(((app.xHome-app.xRover).^2)+((app.yHome-app.yRover).^2));
            catch
                app.D2H = "NaN";
            end
            UpdateRoverTail(app); 
            RoverAndHomePlot(app);
            UpdateRoverNowText(app);
        end
        %-----------------------------------------------------
        function RoverAndHomePlot(app)
            ofst = 100; %m
            % Update Pilot Map
            delete(app.Plt_RoverAtPilotMap);
            delete(app.Plt_HomeAtPilotMap);
            app.Plt_RoverAtPilotMap = plot(app.UIax_ManualDriveMap,app.xRover,app.yRover,'rs','MarkerFaceColor','r','MarkerSize',15);
            app.Plt_HomeAtPilotMap = plot(app.UIax_ManualDriveMap,app.xHome,app.yHome,'^y','MarkerSize',10,'MarkerFaceColor','y');
            lmX = [min([app.xRover,app.xHome])-ofst , max([app.xRover,app.xHome])+ofst];
            lmY = [min([app.yRover,app.yHome])-ofst , max([app.yRover,app.yHome])+ofst];
            xlim(app.UIax_ManualDriveMap,lmX);
            ylim(app.UIax_ManualDriveMap,lmY);
            % Update Auto Pilot Map
            delete(app.Plt_RoverAtSurveyMap);
            delete(app.Plt_HomeAtSurveyMap);
            app.Plt_RoverAtSurveyMap = plot(app.UIax_SurveyMap,app.xRover,app.yRover,'rs','MarkerFaceColor','r','MarkerSize',15);
            app.Plt_HomeAtSurveyMap = plot(app.UIax_SurveyMap,app.xHome,app.yHome,'^y','MarkerSize',10,'MarkerFaceColor','y');
            lmX = [min([app.xHome;app.xRover])-ofst max([app.xHome;app.xRover])+ofst];
            lmY = [min([app.yHome;app.yRover])-ofst max([app.yHome;app.yRover])+ofst];
            xlim(app.UIax_SurveyMap,lmX)
            ylim(app.UIax_SurveyMap,lmY)
        end
        %-----------------------------------------------------
        function [] = UpdateRoverTail(app)
            TailNumber = 10;
            if isempty(app.RoverTail)
                app.RoverTail(1,1:2) = [app.xRover,app.yRover];
            elseif size(app.RoverTail,1) < TailNumber
                app.RoverTail(end+1,1:2) = [app.xRover,app.yRover];
            elseif size(app.RoverTail,1) == TailNumber
                app.RoverTail(1,:) = [];
                app.RoverTail(end+1,:) = [app.xRover,app.yRover];
            end
            delete(app.Plt_RoverTailAtPilotMap)
            delete(app.Plt_RoverTailAtSurveyMap)
            app.Plt_RoverTailAtPilotMap = plot(app.UIax_ManualDriveMap,app.RoverTail(:,1),app.RoverTail(:,2),'.-r');
            app.Plt_RoverTailAtSurveyMap = plot(app.UIax_SurveyMap,app.RoverTail(:,1),app.RoverTail(:,2),'.-r');
        end
        %-----------------------------------------------------
        function BatteryUsage(app,NewBatVolt)
            if NewBatVolt > 45
                if isnan(app.BatUsg(1,1))
                    app.BatUsg(1,1) = 0;
                    app.BatUsg(2,1) = 0;
                    app.TicBattery = tic;
                else
                    % Sum of the battery usage
                    if app.BatteryVGauge.Value >= NewBatVolt
                        app.BatUsg(1,1) = app.BatUsg(1,1) + (app.BatteryVGauge.Value - NewBatVolt);
                        app.BatUsg(2,1) = app.BatUsg(2,1) + toc(app.TicBattery);
                        app.TicBattery = tic;
                        app.BatUsg(3,1) = NewBatVolt / ((app.BatUsg(1,1) / app.BatUsg(2,1)) * 3600);
                        app.BatteryLeftH.Value = app.BatUsg(3,1);
                    end
                end
            end
        end
        %-----------------------------------------------------
        function [] = BatteryColor(app)
            bv = app.BatteryVGauge.Value;
            if bv > 55
                app.BatteryLamp.Color = [0.00,1.00,0.00];
            elseif bv < 55 && bv > 53
                app.BatteryLamp.Color = [1.00,1.00,0.07];
            elseif bv < 53 && bv > 51
                app.BatteryLamp.Color = [1.00,0.41,0.16];
            else
               app.BatteryLamp.Color = [1.00,0.00,0.00];
            end  
        end
        %-----------------------------------------------------
        function [] = RadioConnectionLight(app)
            if app.RadioConnectionFlag == false
                clr = [1.00,0.00,0.00];
            else
                clr = [0.00,1.00,0.00];
            end
            app.RadioLamp.Color = clr;
        end
        %-----------------------------------------------------
        function [x,y,utmzone] = deg2utm(app,Lat,Lon)
            narginchk(3, 3);  %2 arguments required
            n1=length(Lat);
            n2=length(Lon);
            if (n1~=n2)
               error('Lat and Lon vectors should have the same length');
            end
            x=zeros(n1,1);
            y=zeros(n1,1);
            utmzone(n1,:)='60 X';
            for i=1:n1
               la=Lat(i);
               lo=Lon(i);
               sa = 6378137.000000 ; sb = 6356752.314245;
               e2 = ( ( ( sa ^ 2 ) - ( sb ^ 2 ) ) ^ 0.5 ) / sb;
               e2cuadrada = e2 ^ 2;
               c = ( sa ^ 2 ) / sb;
               lat = la * ( pi / 180 );
               lon = lo * ( pi / 180 );
               Huso = fix( ( lo / 6 ) + 31);
               S = ( ( Huso * 6 ) - 183 );
               deltaS = lon - ( S * ( pi / 180 ) );
               if (la<-72), Letra='C';
               elseif (la<-64), Letra='D';
               elseif (la<-56), Letra='E';
               elseif (la<-48), Letra='F';
               elseif (la<-40), Letra='G';
               elseif (la<-32), Letra='H';
               elseif (la<-24), Letra='J';
               elseif (la<-16), Letra='K';
               elseif (la<-8), Letra='L';
               elseif (la<0), Letra='M';
               elseif (la<8), Letra='N';
               elseif (la<16), Letra='P';
               elseif (la<24), Letra='Q';
               elseif (la<32), Letra='R';
               elseif (la<40), Letra='S';
               elseif (la<48), Letra='T';
               elseif (la<56), Letra='U';
               elseif (la<64), Letra='V';
               elseif (la<72), Letra='W';
               else 
                   Letra='X';
               end
               a = cos(lat) * sin(deltaS);
               epsilon = 0.5 * log( ( 1 +  a) / ( 1 - a ) );
               nu = atan( tan(lat) / cos(deltaS) ) - lat;
               v = ( c / ( ( 1 + ( e2cuadrada * ( cos(lat) ) ^ 2 ) ) ) ^ 0.5 ) * 0.9996;
               ta = ( e2cuadrada / 2 ) * epsilon ^ 2 * ( cos(lat) ) ^ 2;
               a1 = sin( 2 * lat );
               a2 = a1 * ( cos(lat) ) ^ 2;
               j2 = lat + ( a1 / 2 );
               j4 = ( ( 3 * j2 ) + a2 ) / 4;
               j6 = ( ( 5 * j4 ) + ( a2 * ( cos(lat) ) ^ 2) ) / 3;
               alfa = ( 3 / 4 ) * e2cuadrada;
               beta = ( 5 / 3 ) * alfa ^ 2;
               gama = ( 35 / 27 ) * alfa ^ 3;
               Bm = 0.9996 * c * ( lat - alfa * j2 + beta * j4 - gama * j6 );
               xx = epsilon * v * ( 1 + ( ta / 3 ) ) + 500000;
               yy = nu * v * ( 1 + ta ) + Bm;
               if (yy<0)
                   yy=9999999+yy;
               end
               x(i)=xx;
               y(i)=yy;
               utmzone(i,:)=sprintf('%02d %c',Huso,Letra);
            end
        end
        %-----------------------------------------------------
        function [Lat,Lon] = utm2deg(app,xx,yy,utmzone)
            narginchk(4, 4); %3 arguments required
            n1=length(xx);
            n2=length(yy);
            n3=size(utmzone,1);
            if (n1~=n2 || n1~=n3)
               error('x,y and utmzone vectors should have the same number or rows');
            end
            c=size(utmzone,2);
            if (c~=4)
               error('utmzone should be a vector of strings like "30 T"');
            end
            Lat=zeros(n1,1);
            Lon=zeros(n1,1);
            for i=1:n1
               if (utmzone(i,4)>'X' || utmzone(i,4)<'C')
                  fprintf('utm2deg: Warning utmzone should be a vector of strings like "30 T", not "30 t"\n');
               end
               if (utmzone(i,4)>'M')
                  hemis='N';   % Northern hemisphere
               else
                  hemis='S';
               end
               x=xx(i);
               y=yy(i);
               zone=str2double(utmzone(i,1:2));
               sa = 6378137.000000 ; sb = 6356752.314245;
               e2 = ( ( ( sa ^ 2 ) - ( sb ^ 2 ) ) ^ 0.5 ) / sb;
               e2cuadrada = e2 ^ 2;
               c = ( sa ^ 2 ) / sb;
               X = x - 500000;
               if hemis == 'S' || hemis == 's'
                   Y = y - 10000000;
               else
                   Y = y;
               end
               S = ( ( zone * 6 ) - 183 ); 
               lat =  Y / ( 6366197.724 * 0.9996 );                                    
               v = ( c / ( ( 1 + ( e2cuadrada * ( cos(lat) ) ^ 2 ) ) ) ^ 0.5 ) * 0.9996;
               a = X / v;
               a1 = sin( 2 * lat );
               a2 = a1 * ( cos(lat) ) ^ 2;
               j2 = lat + ( a1 / 2 );
               j4 = ( ( 3 * j2 ) + a2 ) / 4;
               j6 = ( ( 5 * j4 ) + ( a2 * ( cos(lat) ) ^ 2) ) / 3;
               alfa = ( 3 / 4 ) * e2cuadrada;
               beta = ( 5 / 3 ) * alfa ^ 2;
               gama = ( 35 / 27 ) * alfa ^ 3;
               Bm = 0.9996 * c * ( lat - alfa * j2 + beta * j4 - gama * j6 );
               b = ( Y - Bm ) / v;
               Epsi = ( ( e2cuadrada * a^ 2 ) / 2 ) * ( cos(lat) )^ 2;
               Eps = a * ( 1 - ( Epsi / 3 ) );
               nab = ( b * ( 1 - Epsi ) ) + lat;
               senoheps = ( exp(Eps) - exp(-Eps) ) / 2;
               Delt = atan(senoheps / (cos(nab) ) );
               TaO = atan(cos(Delt) * tan(nab));
               longitude = (Delt *(180 / pi ) ) + S;
               latitude = ( lat + ( 1 + e2cuadrada* (cos(lat)^ 2) - ( 3 / 2 ) * e2cuadrada * sin(lat) * cos(lat) * ( TaO - lat ) ) * ( TaO - lat ) ) * ...
                                (180 / pi);
               Lat(i)=latitude;
               Lon(i)=longitude;
            end
        end
        %-----------------------------------------------------
        function SAR_Mode(app)
            if app.StartHereCheckBox.Value == 1
                P = [app.X1st,app.Y1st;app.Xlast,app.Ylast];
            else
                d = 20;
                m = (app.Ylast-app.Y1st)./(app.Xlast-app.X1st);
                syms x3
                eqn = d == sqrt((1+(m.^2)).*((x3-app.X1st).^2));
                x3 = double(solve(eqn,x3));
                y3 = app.Y1st + (m.*(x3-app.X1st));
                dd(1) = round(sqrt((x3(1)-app.Xlast).^2 + (y3(1)-app.Ylast).^2),2);
                dd(2) = round(sqrt((x3(2)-app.Xlast).^2 + (y3(2)-app.Ylast).^2),2);
                [~,ix3] = max(dd);
                x3 = x3(ix3);
                y3 = y3(ix3);
                P = [x3,y3;app.X1st,app.Y1st;app.Xlast,app.Ylast];
            end
            D = 0;
            for i = 2:size(P,1)
                d = sqrt( ( ( P(i,1)-P(i-1,1) ).^2 ) + ( ( P(i,2)-P(i-1,2) ).^2 ) );
                D = D + d;
            end
            if ~isnan(app.xHome) && ~isnan(app.yHome)
                LP2H = max(round(sqrt((app.xHome - P(end,1)).^2 + (app.yHome - P(end,2)).^2),2));
            else
                LP2H = 0;
            end
            app.SurveyPoints = P;
            app.NsurveyPoints.Value = size(P,1);
            app.SurveyKMEdit.Value = round((D./1000),1);
            app.LP2HEdit.Value = LP2H;
        end
        %-----------------------------------------------------
        function Point_Mode(app)
            P = [app.Xlast,app.Ylast];
            D = round(sqrt((app.Xlast-app.xRover).^2 + (app.Ylast-app.yRover).^2),2);
            if ~isnan(app.xHome) && ~isnan(app.yHome)
                LP2H = max(round(sqrt((app.xHome - P(end,1)).^2 + (app.yHome - P(end,2)).^2),2));
            else
                LP2H = 0;
            end
            app.SurveyPoints = P;
            app.NsurveyPoints.Value = size(P,1);
            app.SurveyKMEdit.Value = round((D./1000),1);
            app.LP2HEdit.Value = LP2H;
        end
        %-----------------------------------------------------
        function Profile_Mode(app)
            Spacing = app.XmEditSpacing.Value;
            m = (app.Ylast-app.Y1st)./(app.Xlast-app.X1st);
            refX = app.X1st;
            refY = app.Y1st;
            P = [app.X1st,app.Y1st];
            pcounter = 0;
            while true
                syms x3
                eqn = Spacing == sqrt((1+(m.^2)).*((x3-refX).^2));
                x3 = double(solve(eqn,x3));
                y3 = refY + (m.*(x3-refX));
                d(1) = round(sqrt((x3(1)-app.Xlast).^2 + (y3(1)-app.Ylast).^2),2);
                d(2) = round(sqrt((x3(2)-app.Xlast).^2 + (y3(2)-app.Ylast).^2),2);
                [~,ix3] = min(d);
                refX = x3(ix3);
                refY = y3(ix3);
                P = [P;refX,refY];
                D = round(sqrt((x3(ix3)-app.Xlast).^2 + (y3(ix3)-app.Ylast).^2),2);
                if abs(D) < Spacing
                    break
                end
                pcounter = pcounter +1;
            end
            if P(1,end) ~= app.Xlast || P(2,end) ~= app.Ylast
                P = [P;app.Xlast,app.Ylast];
            end
            D = 0;
            for i = 2:size(P,1)
                d = sqrt( ( ( P(i,1)-P(i-1,1) ).^2 ) + ( ( P(i,2)-P(i-1,2) ).^2 ) );
                D = D + d;
            end
            if ~isnan(app.xHome) && ~isnan(app.yHome)
                LP2H = max(round(sqrt((app.xHome - P(end,1)).^2 + (app.yHome - P(end,2)).^2),2));
            else
                LP2H = 0;
            end
            app.SurveyPoints = P;
            app.NsurveyPoints.Value = size(P,1);
            app.SurveyKMEdit.Value = round((D./1000),1);
            app.LP2HEdit.Value = LP2H;
        end
        %-----------------------------------------------------
        function Area_Mode(app)
            xspc = app.XmEditSpacing.Value;
            yspc = app.YmEditSpacing.Value;
            if app.X1st > app.Xlast
                xspc = -xspc;
            end
            xp = app.X1st:xspc:app.Xlast;
            if xp(end) ~= app.Xlast
                app.Xlast = xp(end) + xspc;
                xp(end+1) = app.Xlast;
            end
            if app.Y1st > app.Ylast
                yspc = -yspc;
            end
            yp = app.Y1st:yspc:app.Ylast;
            if yp(end) ~= app.Ylast
                app.Ylast = yp(end) + yspc;
                yp(end+1) = app.Ylast;
            end
            [X,Y] = meshgrid(xp,yp);
            C = [X(:) Y(:)];
            ptemp = C;
            inxt = 1;
            pnxt = ptemp(inxt,:);
            ptemp(inxt,:) = [];
            sortP = pnxt;
            while true
               if isempty(ptemp)
                   break
               end
               [~,inxt] = min(round(sqrt((pnxt(1,1)-ptemp(:,1)).^2 + (pnxt(1,2)-ptemp(:,2)).^2),2));
               pnxt = ptemp(inxt,:);
               ptemp(inxt,:) = [];
               sortP = [sortP ; pnxt];
            end
            P = sortP;
            d(1) = 0;
            D = 0;
            for i = 2:size(P,1)
                d = sqrt( ( ( P(i,1)-P(i-1,1) ).^2 ) + ( ( P(i,2)-P(i-1,2) ).^2 ) );
                D = D + d;
            end
            if ~isnan(app.xHome) && ~isnan(app.yHome)
                LP2H = max(round(sqrt((app.xHome - P(end,1)).^2 + (app.yHome - P(end,2)).^2),2));
            else
                LP2H = 0;
            end
            app.SurveyPoints = sortP;
            app.NsurveyPoints.Value = size(sortP,1);
            app.SurveyKMEdit.Value = round((D./1000),1);
            app.LP2HEdit.Value = LP2H;
        end
        %-----------------------------------------------------
        function [] = CreateSurveyMessage(app)
            SP = app.SurveyPoints;
            % convert the utm points to lat and long
            utmz = app.utmZ1;
            if size(utmz) ~= size(SP(:,1))
                utmz = repmat(app.utmZ1,size(SP,1),1);
            end
            [Lat,Lon] = utm2deg(app,SP(:,1),SP(:,2),utmz);
            SP = [Lat,Lon];
            % create a string message
            nPoints = size(SP,1);
            if app.AdventureButton.Value == 1
                command = "#AdventureSurvey,"+string(nPoints);
            elseif app.PolarimetricButton.Value == 1
                command = "#PolarimetricSurvey,"+string(nPoints);
            elseif app.SARButton.Value == 1
               command = "#SARSurvey,"+string(app.XmEditSpacing.Value); 
            end
            msg = "";
            for i = 1:nPoints
                msg = msg+","+string(SP(i,1))+","+string(SP(i,2));
            end
            app.SurveyPointsMSG = char(command+msg+"$");
        end
        %-----------------------------------------------------
        function [] = LoadSurvey(app)
            [file,path] = uigetfile({'*.txt';'*.csv';'*.*'},'Select your survey');
            loadfilepath = strcat(path,file);        
            if ~isempty(loadfilepath)
                T = readtable(strcat(path,file));
                [x,y,app.utmZ1] = deg2utm(app,T.Lat,T.Lon);
    %             clmns = T.Properties.VariableNames;
    %             if clmns{1} == "Lat" && clmns{2} == "Lon"
    %                 [x,y,app.utmZ1] = deg2utm(app,T.Lat,T.Lon);
    %             else
    %                 x = T.X;
    %                 y = T.Y;
    %                 app.utmZ1 = T.Zone;
    %             end
                d(1) = 0;
                D = 0;
                for i = 2:size(x)
                    d = sqrt( ( ( x(i)-x(i-1) ).^2 ) + ( ( y(i)-y(i-1) ).^2 ) );
                    D = D + d;
                end
                if ~isnan(app.xHome) && ~isnan(app.yHome)
                    LP2H = max(round(sqrt((app.xHome - x(end)).^2 + (app.yHome - y(end)).^2),2));
                else
                    LP2H = 0;
                end
                app.SurveyPoints = [x,y];
                app.NsurveyPoints.Value = size(app.SurveyPoints,1);
                app.SurveyKMEdit.Value = round((D./1000),1);
                app.LP2HEdit.Value = LP2H;
                app.LatEditFirst.Value = T.Lat(1);
                app.LonEditFirst.Value = T.Lon(1);
                if contains(file,'Adventure')
                    app.AdventureButton.Value = 1;
                elseif contains(file,'Polarimetric')
                    app.PolarimetricButton.Value = 1;
                elseif contains(file,'SAR')
                    app.SARButton.Value = 1;
                    app.LatEditFirst.Value = T.Lat(2);
                    app.LonEditFirst.Value = T.Lon(2);
                    app.XmEditSpacing.Value = T.Spacing(1);
                    app.YmEditSpacing.Value = T.Spacing(1);
                elseif contains(file,'Advanced')
    %                 app.ActP = T.Act;
                end
                app.LatEditLast.Value = T.Lat(end);
                app.LonEditLast.Value = T.Lon(end);
                if ~isempty(app.SurveyPoints)
                    app.SimulateButton.Enable = 1;
                    app.SENDTOARKButton.Enable = 1;
                    app.SAVEButton.Enable = 1;
                    StaticSurveyPointsPlot(app,app.SimAx);
                end
            end
        end
        %-----------------------------------------------------
        function StaticSurveyPointsPlot(app,whichAx)
            ofst = 100; %m
            cla(whichAx);
%             plot(whichAx,app.X1st,app.Y1st,'oc','MarkerSize',20,'MarkerFaceColor','c')
%             hold(whichAx,'on')
            plot(whichAx,app.SurveyPoints(:,1),app.SurveyPoints(:,2),'oc','MarkerSize',10,'MarkerFaceColor','c')
            hold(whichAx,'on')
            plot(whichAx,app.Xlast,app.Ylast,'xr','MarkerSize',20,'linewidth',2)
            plot(whichAx,app.xHome,app.yHome,'^y','MarkerSize',10,'MarkerFaceColor','y');
            plot(whichAx,app.xRover,app.yRover,'rs','MarkerFaceColor','r','MarkerSize',20);
            lmX = [min([app.SurveyPoints(:,1);app.xRover])-ofst max([app.SurveyPoints(:,1);app.xRover])+ofst];
            lmY = [min([app.SurveyPoints(:,2);app.yRover])-ofst max([app.SurveyPoints(:,2);app.yRover])+ofst];
            xlim(whichAx,lmX);
            ylim(whichAx,lmY);
%             set(app.SimAx,'color','k')
%             grid(app.SimAx,'on')
%             set(app.SimAx,'GridColor','g')
%             set(app.SimAx,'LineWidth',2)
        end
        %-----------------------------------------------------
        function UpdateRoverNowText(app)
            app.RoverNowText.Value = ...
                {char(app.WhichGPS+":"+app.GPSQuality);...
                char("Time:| "+string(app.gpsTime)+" ("+num2str(app.gpsAge,"%.1f")+")");...
                char("latR.[dd]:| "+num2str(app.RoverLatDD,"%.7f"));...
                char("lonR.[dd]:| "+num2str(app.RoverLngDD,"%.7f"));...
                char("zR. [msl]:| "+num2str(app.Altitude,"%.1f"));...
                char("cR. [deg]:| "+num2str(app.Course,"%.1f"));...
                char("latH.[dd]:| "+num2str(app.HomeLatDD,"%.7f"));...
                char("lonH.[dd]:| "+num2str(app.HomeLonDD,"%.7f"));...
                char("D2H   [m]:| "+num2str(app.D2H,"%.2f"))...
                };
        end
        %-----------------------------------------------------
        function W_SliderCheck(app)
            ZeroVlt = 2056;
            f = app.VFactorEditField.Value;
            eps = app.WFactorEditField.Value;
            wof = app.WOffsetEditField.Value;
            v = app.VSlider.Value;
            w = app.WSlider.MajorTicks;
            MaxVms = 3;
            if v < 10
                app.WSlider.Value = 0;
                app.WSlider.MajorTickLabels = repmat("-",1,length(w));
                wEn = 0;
            else
                v = (v.*f./100)+ZeroVlt;
                VLvlt = v + (eps.*(w+wof));
                VRvlt = v - (eps.*(w+wof));
                VLvlt(VLvlt<339) = 339;
                VRvlt(VRvlt<339) = 339;
                VLvlt(VLvlt>3773) = 3773;
                VRvlt(VRvlt>3773) = 3773;
                VLms = (VLvlt-ZeroVlt).*MaxVms./f;
                VRms = (VRvlt-ZeroVlt).*MaxVms./f;
                R = (app.L./2) .* ((VLms+VRms)./(VLms-VRms));
                app.WSlider.MajorTickLabels = string(round(R,1));
                wEn = 1;
            end
            app.Right20Button.Enable = wEn;
            app.Left20Button.Enable = wEn;
            app.WSlider.Enable = wEn;
        end
        %-----------------------------------------------------
        function sendCMD(app,CMD)
            try
                write(app.RadioDevice,CMD,"char");
                pause(0.25);
                if string(CMD) ~= "#pong$"
                    UpdateRadioStream(app,string(CMD))
                end
                if app.ErrorLamp.Enable == 1
                    app.ErrorLamp.Enable = 0;
                end
            catch ME
                disp(ME)
                if app.ErrorLamp.Enable == 0
                    app.ErrorLamp.Enable = 1;
                end
                UpdateRadioStream(app," *ERROR* C.S. can't send command")
            end
        end
        %-----------------------------------------------------
        function selection = ExitQuestion(~)
            fig = uifigure;
            msg = 'Do you want to close the app?';
            title = 'EXIT';
            selection = uiconfirm(fig,msg,title,...
           'Options',{'YES','NO'},...
           'DefaultOption',2);
            close(fig);
        end
        %-----------------------------------------------------
        
        %-----------------------------------------------------
        
        %-----------------------------------------------------
        
        %-----------------------------------------------------
        function [] = MainLoop(app)
            app.MainLoopRunningFlag = true;
            while true
                % check the radio connection and messages (TEL)
                RadioUpdate(app); 
                % exit the main loop
                if app.ExitMainLoopFlag == true || app.ExitAppFlag == true 
                    app.MainLoopRunningFlag = false;
                    break
                end
                pause(0.1);
            end
            app.MainLoopRunningFlag = false;
        end
        %-----------------------------------------------------
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % initiate "exit the app" flag
            app.ExitAppFlag = false;
            app.MainLoopRunningFlag = false;
            % initiate linear and differential increment 
            app.vInc = 10;
            app.wInc = 35;
            app.L = 1.23;
            app.vDir = 1;
            %
            app.BatUsg = NaN(3,1);
            %
            app.E = referenceEllipsoid("wgs84","meter");
            app.RoverLatDD = -70;
            app.RoverLngDD = -8;
            [app.xRover,app.yRover,~] = deg2utm(app,app.RoverLatDD,app.RoverLngDD);
%             app.xRover = nan;
%             app.yRover = nan;
            app.HomeLatDD = nan;
            app.HomeLonDD = nan;
            app.xHome = nan;
            app.yHome = nan;
            app.SurveyPoints = [];
            hold(app.UIax_ManualDriveMap,'on');
            hold(app.UIax_SurveyMap,'on');
            %
            app.gpsqual = ["NotAvailable"
                            "GPSfix"
                            "DifferentialGPS"
                            "-"
                            "RTKint"
                            "RTKfloat"];
            %
            app.RadioBaudRate = 38400;
            app.RadioTimeOut = 10;
            % initiate the Radio stream text box
            app.RadioString = "";
            app.RadioTxt = "";
            newtxt = [" -----------------------------"," Hey there!!!"];
            UpdateRadioStream(app,newtxt)
            % initiate the radio device object
            app.RadioDevice = [];
            app.RadioConnectionFlag = false;
            % update the ports dropdown
            app.RadioPortDropDown.Items = ["Select a RADIO port",serialportlist];
            pause(0.25);
        end

        % Value changed function: RadioPortDropDown
        function RadioPortDropDownValueChanged(app, event)
            if string(app.RadioPortDropDown.Value) == "Select a RADIO port"
                app.RADIOSwitch.Enable = 0;
            else % if an existing port is selected: try to create a new RadioObj
                CreateRadioDeviceObj(app);
            end
            app.RadioPortDropDown.Items = ["Select a RADIO port",serialportlist];
        end

        % Value changed function: RADIOSwitch
        function RADIOSwitchChanged(app, event)
            % the connect switch will enter and exit the main loop
            if app.RADIOSwitch.Value == "Connect" % enter the main loop
%                 sendCMD(app,char("#CMDSettingsQuerry$"));
                app.ExitMainLoopFlag = false;
                app.ticRadio = tic;
                MainLoop(app);
                app.RADIOSwitch.Value = "Disconnect";
                app.RadioLamp.Color = [1.00,0.00,0.00];
                if app.ExitAppFlag == true 
                    app.UIFigureCloseRequest(app)
                end
            else % exit the main loop
%                 EMERGENCYSTOPButtonPushed(app)
                app.ExitMainLoopFlag = true;
                app.ExitAppFlag = false;
                % update the ports dropdown
                app.RadioPortDropDown.Items = ["Select a RADIO port",serialportlist];
            end
        end

        % Value changed function: HandBreakSwitch_1
        function HandBreakSwitch_1_Changed(app, event)
            if app.HandBreakSwitch_1.Value == "Up"
                app.HandBreakSwitch_2.Value = "Up";
                app.HandBreakLamp.Enable = 1;
                STOPButtonPushed(app, event)
            else
                app.HandBreakSwitch_2.Value = "Down";
                app.HandBreakLamp.Enable = 0;
            end
        end

        % Value changed function: HandBreakSwitch_2
        function HandBreakSwitch_2_Changed(app, event)
            if app.HandBreakSwitch_2.Value == "Up"
                app.HandBreakSwitch_1.Value = "Up";
                app.HandBreakLamp.Enable = 1;
                STOPButtonPushed(app, event)
            else
                app.HandBreakSwitch_1.Value = "Down";
                app.HandBreakLamp.Enable = 0;
            end
        end

        % Value changed function: LowBatteryModeSwitch
        function LowBatteryModeSwitch_Changed(app, event)
            if app.LowBatteryModeSwitch.Value == "On"
                clr = [0.00,1.00,0.00];
                LowBatteryMod = "true";
            else
                clr = [1.00,0.00,0.00];
                LowBatteryMod = "false";
            end
            CMD = char("#CMDSetLowBatt,"+LowBatteryMod+"$");
            sendCMD(app,CMD);
            app.LBMLamp.Color = clr;
        end

        % Value changed function: DriveModeSwitch
        function DriveModeSwitchValueChanged(app, event)
            if app.DriveModeSwitch.Value == "Auto"
                CMD = '#CMDDriveMode,Autonomous$';
                sendCMD(app,CMD);
                clr = [0.00,1.00,0.00];
            else
                CMD = '#CMDDriveMode,Manual$';
                sendCMD(app,CMD);
                app.EMERGENCYSTOPButtonPushed(app)
                clr = [1.00,0.00,0.00];
            end  
            app.APilotLamp.Color = clr;
        end

        % Value changed function: IgnoreInstrumentSwitch
        function IgnoreInstrumentSwitch_Changed(app, event)
            if app.IgnoreInstrumentSwitch.Value == "On"
                IgnoreInstrument = "ignore";
                clr = [0.00,1.00,0.00];
            else
                IgnoreInstrument = "listen";
                clr = [1.00,0.00,0.00];
            end  
            CMD = char("#CMDSetIgnoreInstrument,"+IgnoreInstrument+"$");
            sendCMD(app,CMD);
            app.IgnoreInstLamp.Color = clr;
        end

        % Value changed function: ReverseSwitch
        function ReverseGearSwitch(app, event)
            if app.ReverseSwitch.Value == "On"
                app.vDir = -1;
            else
                app.vDir = 1;
            end  
        end

        % Value changed function: ComeHomeSwitch
        function ComeHomeSwitch_Changed(app, event)
            if app.ComeHomeSwitch.Value == "On"
                % switches the rover to auto pilot
                % with home coordinate as the goal
                EMERGENCYSTOPButtonPushed(app);
                app.SurveyPointsMSG = char("#ComeHome$");
                sendCMD(app,app.SurveyPointsMSG);
                app.DriveModeSwitch.Value = "Auto";
            else
                EMERGENCYSTOPButtonPushed(app)
            end
        end

        % Button pushed function: SwitchGPSButton
        function SwitchGPSButtonPushed(app, event)
            sendCMD(app,"#SwitchGPS$");
        end

        % Button pushed function: UpdateHomeButton
        function UpdateHome_Pushed(app, event)
            sendCMD(app,char("#UpdateHome$"));
        end

        % Value changed function: VSlider, WSlider
        function VWslider_Moved(app, event)
            if app.HandBreakSwitch_1.Value == "Down"
                W_SliderCheck(app);
                v = app.vDir .* app.VSlider.Value;
                w = app.WSlider.Value;
                CMD = char("#CMDSetSpeed,"+string(v)+","+string(w)+"$");
                sendCMD(app,CMD);
            else
                app.VSlider.Value = 0;
                app.WSlider.Value = 0;
            end
        end

        % Button pushed function: SpeedUp10Button
        function SpeedUp10ButtonPushed(app, event)
            if app.HandBreakSwitch_1.Value == "Down"
                nval = app.VSlider.Value + app.vInc;
                if nval >= 100
                    app.VSlider.Value = 100;
                else
                    app.VSlider.Value = nval;
                end
                VWslider_Moved(app, event)
            end
        end

        % Button pushed function: SlowDown10Button
        function SlowDown10ButtonPushed(app, event)
            if app.HandBreakSwitch_1.Value == "Down"
                nval = app.VSlider.Value - app.vInc;
                if nval <= 0
                    app.VSlider.Value = 0;
                else
                    app.VSlider.Value = nval;
                end
                VWslider_Moved(app, event)
            end
        end

        % Button pushed function: Right20Button
        function Right20ButtonPushed(app, event)
            if app.HandBreakSwitch_1.Value == "Down"
                nval = app.WSlider.Value + app.wInc;
                if nval >= 140
                    app.WSlider.Value = 140;
                else
                    app.WSlider.Value = nval;
                end
                VWslider_Moved(app, event)
            end
        end

        % Button pushed function: Left20Button
        function Left20ButtonPushed(app, event)
            if app.HandBreakSwitch_1.Value == "Down"
                nval = app.WSlider.Value - app.wInc;
                if nval <= -140
                    app.WSlider.Value = -140;
                else
                    app.WSlider.Value = nval;
                end
                VWslider_Moved(app, event)
            end
        end

        % Button pushed function: StraightButton, StraightButton2
        function StraightButton_Pushed(app, event)
            app.WSlider.Value = 0;
            VWslider_Moved(app, event)
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            CMD = '#CMDStop$';
            sendCMD(app,CMD);
            app.VSlider.Value = 0;
            app.WSlider.Value = 0;
            W_SliderCheck(app);
        end

        % Button pushed function: EMERGENCYSTOPButton
        function EMERGENCYSTOPButtonPushed(app, event)
            CMD = '#CMDEmStop$';
            sendCMD(app,CMD);
            app.VSlider.Value = 0;
            app.WSlider.Value = 0;
            W_SliderCheck(app);
            if app.DriveModeSwitch.Value == "Auto"
                app.DriveModeSwitch.Value = "Manual";
            end
            if app.ComeHomeSwitch.Value == "On"
                app.ComeHomeSwitch.Value = "Off";
            end
        end

        % Button pushed function: LoadButton
        function LoadCoordinates_Pushed(app, event)
            LoadSurvey(app);
            if ~isempty(app.SurveyPoints)
                app.SENDTOARKButton.Enable = 1;
            else
                app.SENDTOARKButton.Enable = 0;  
            end
        end

        % Value changed function: XmEditSpacing
        function SurveyXspacing(app, event)
            if app.ProfileButton.Value == 1 || app.SARButton.Value == 1
                app.YmEditSpacing.Value = app.XmEditSpacing.Value;
            end
        end

        % Value changed function: YmEditSpacing
        function SurveyYspacing(app, event)
            if app.ProfileButton.Value == 1 || app.SARButton.Value == 1
                app.XmEditSpacing.Value = app.YmEditSpacing.Value;
            end
        end

        % Value changed function: SimulateButton
        function SimulateTheSurvey_Pushed(app, event)
            prover = [];
            ppast = [];
            P = app.SurveyPoints;
            for i = 1:size(P,1)
                if i > 1
                    delete(ppast);
                    ppast = plot(app.SimAx,P(1:i,1),P(1:i,2),'g-o','MarkerFaceColor','g');
                end
                delete(prover);
                prover = plot(app.SimAx,P(i,1),P(i,2),'rs','MarkerFaceColor','r','MarkerSize',15);
                drawnow
                pause(0.1)
            end
            plot(app.SimAx,[app.xHome app.Xlast],[app.yHome app.Ylast],'--y')
        end

        % Value changed function: ClearButton
        function DeleteSimulation_Pushed(app, event)
            app.SurveyPoints = [];
            app.SimulateButton.Enable = 0;
            app.SENDTOARKButton.Enable = 0;
            app.SAVEButton.Enable = 0;
            app.NsurveyPoints.Value = 0;
            app.SurveyKMEdit.Value = 0;
            app.LP2HEdit.Value = 0;
            cla(app.SimAx);
            pause(0.2);
        end

        % Button pushed function: SENDTOARKButton
        function SendSimulationToARK_Pushed(app, event)
            %% Create a string message from the survey points
            if app.DriveModeSwitch.Value == "Auto"
                app.DriveModeSwitch.Value = "Manual";
                DriveModeSwitchValueChanged(app)
            end
            CreateSurveyMessage(app);
            %% send it to ARK
            CMD = app.SurveyPointsMSG;
            sendCMD(app,CMD);
            %%
            app.SurveyPointsInARK = app.SurveyPoints;
            app.NsurveyPoints_2.Value = app.NsurveyPoints.Value;
            app.nCurrentPoint.Value = 0;
            app.SurveyKMEdit_2.Value = app.SurveyKMEdit.Value;
            app.LP2HEdit_2.Value = app.LP2HEdit.Value;
            StaticSurveyPointsPlot(app,app.UIax_SurveyMap);
            DeleteSimulation_Pushed(app, event)
            app.DriveModeSwitch.Enable = 1;
        end

        % Value changed function: TelemetryIntervalsEditField
        function TelemetryIntervals_Changed(app, event)
            TelInterval = app.TelemetryIntervalsEditField.Value;
            CMD = char("#CMDSetTelemetryInterval,"+string(TelInterval)+"$");
            sendCMD(app,CMD);
        end

        % Value changed function: AutoMaxSpeedEditField
        function AutonomousMaxSpeed_Changed(app, event)
            value = app.AutoMaxSpeedEditField.Value;
            CMD = char("#CMDAutonomousMaxSpeed,"+string(value)+"$");
            sendCMD(app,CMD);
        end

        % Value changed function: SARMaxSpeedEditField
        function SARMaxSpeedEditFieldValueChanged(app, event)
            value = app.SARMaxSpeedEditField.Value;
            CMD = char("#CMDSARMaxSpeed,"+string(value)+"$");
            sendCMD(app,CMD);
        end

        % Value changed function: NavigationRadiusmEditField
        function NavigationRadius_Changed(app, event)
            value = app.NavigationRadiusmEditField.Value;
            CMD = char("#CMDSetNavRad,"+string(value)+"$");
            sendCMD(app,CMD);
        end

        % Value changed function: WOffsetEditField
        function WOffset_Changed(app, event)
            value = app.WOffsetEditField.Value;
            CMD = char("#CMDSetWOffset,"+string(value)+"$");
            sendCMD(app,CMD);
        end

        % Button pushed function: SettingsQueryButton
        function SettingsQuery_Pushed(app, event)
            CMD = char("#CMDSettingsQuery$");
            sendCMD(app,CMD);
        end

        % Value changed function: WaypointRadiusmEditField
        function WaypointRadius_Changed(app, event)
            value = app.WaypointRadiusmEditField.Value;
            CMD = char("#CMDSetWayPointRad,"+string(value)+"$");
            sendCMD(app,CMD);
        end

        % Value changed function: ICoefficientEditField, 
        % ...and 3 other components
        function PIAD_Coefficients_Changed(app, event)
            P = app.PCoefficientEditField.Value;
            I = app.ICoefficientEditField.Value;
            D = app.SpeedPCoeffEditField.Value;
            A = app.RoverPositionErrorEditField.Value;
            CMD = char("#CMDSetPID,"+string(P)+","+string(I)+","+string(D)+","+string(A)+"$");
            sendCMD(app,CMD);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end

        % Callback function: EXITButtonPushed, EXITMenu
        function ClosetheappButtonPushed(app, event)
            selection = ExitQuestion(app);
            if selection == "YES"
                app.ExitMainLoopFlag = true;
                app.ExitAppFlag = true;
                if app.RADIOSwitch.Value == "Connect"
                    app.RADIOSwitch.Value = "Disconnect";
                    RADIOSwitchChanged(app, event)
                    pause(0.1);
                end
                if app.MainLoopRunningFlag == false
                    app.UIFigureCloseRequest(app);
                end
            end
        end

        % Button pushed function: ARK_PowerPushed
        function ARK_PowerPushedPushed(app, event)
            fig = uifigure;
            msg = 'ARK';
            title = 'ARK';
            selection = uiconfirm(fig,msg,title,...
                       'Options',{'Shutdown','Restart','Cancel'},...
                       'DefaultOption',3,'CancelOption',3);
            if selection == "Shutdown"
                ShutdownMenuSelected(app, event)
            elseif selection == "Restart"
                RestartMenuSelected(app, event)
            end
        end

        % Menu selected function: RestartMenu
        function RestartMenuSelected(app, event)
            fig = uifigure;
            msg = 'Do you want to Restart ARK?';
            title = 'Restart ARK';
            selection = uiconfirm(fig,msg,title,...
           'Options',{'YES','NO'},...
           'DefaultOption',2);
            close(fig);
            if selection == "YES"
                CMD = char("#ARK_OS,reboot$");
                sendCMD(app,CMD); 
            end
        end

        % Menu selected function: ShutdownMenu
        function ShutdownMenuSelected(app, event)
            fig = uifigure;
            msg = 'Do you want to Shutdown ARK?';
            title = 'Shutdown ARK';
            selection = uiconfirm(fig,msg,title,...
           'Options',{'YES','NO'},...
           'DefaultOption',2);
            close(fig);
            if selection == "YES"
                CMD = char("#ARK_OS,poweroff$");
                sendCMD(app,CMD); 
            end
        end

        % Button pushed function: SendCMDButton
        function SendCMDButtonPushed(app, event)
            CMD = char("#"+app.SendCMDBox.Value+"$");
            sendCMD(app,CMD);
            app.SendCMDBox.Value = "";
            app.SendCMDButton.Enable = 0;
        end

        % Value changed function: SendCMDBox
        function SendCMDBoxValueChanged(app, event)
            if length(app.SendCMDBox.Value) > 5
                app.SendCMDButton.Enable = 1;
            else
                app.SendCMDButton.Enable = 0;
            end
        end

        % Button pushed function: UpdateFigureButton
        function UpdateFigureButtonPushed(app, event)
            v1 = app.SurveyTypeDesign.SelectedObject.Text;
            v2 = app.SurveyModeDesign.SelectedObject.Text;
            v3 = app.StartHereCheckBox.Value;
            %-------------
            latDest2 = app.LatEditLast.Value;
            lonDest2 = app.LonEditLast.Value;
            if app.InputFormat.Value == "Distance/Azimuth"
                [latDest2,lonDest2] = reckon(app.RoverLatDD,app.RoverLngDD,latDest2,lonDest2,app.E);
            end
            [app.Xlast,app.Ylast,app.utmZ1] = deg2utm(app,latDest2,lonDest2);
            %-------------
            if v3 == 1
                StartHereCheckBoxValueChanged(app)
            end
            latDest1 = app.LatEditFirst.Value;
            lonDest1 = app.LonEditFirst.Value;
            if app.InputFormat.Value == "Distance/Azimuth" && v3==0
                [latDest1,lonDest1] = reckon(app.RoverLatDD,app.RoverLngDD,latDest1,lonDest1,app.E);
            end
            [app.X1st,app.Y1st,app.utmZ1] = deg2utm(app,latDest1,lonDest1);
            % check whcih function needs to run
            % according to the survey type and points 
            if v1 == "SAR"
                SAR_Mode(app);
            else
                if v2 == "Point"
                    Point_Mode(app);
                elseif v2 == "Profile"
                    Profile_Mode(app);
                elseif v2 == "Area"
                    Area_Mode(app);
                end
            end
            % Static Plot of the survey points
            if ~isempty(app.SurveyPoints)
                app.SimulateButton.Enable = 1;
                app.SENDTOARKButton.Enable = 1;
                app.SAVEButton.Enable = 1;
                StaticSurveyPointsPlot(app,app.SimAx);
            else
                app.SimulateButton.Enable = 0;
                app.SENDTOARKButton.Enable = 0;
                app.SAVEButton.Enable = 0;
            end
        end

        % Button pushed function: SAVEButton
        function SAVEButtonPushed(app, event)
            Type1 = app.SurveyTypeDesign.SelectedObject.Text;
            Type2 = app.SurveyModeDesign.SelectedObject.Text;
            SP = app.SurveyPoints;
            [Lat,Lon] = utm2deg(app,SP(:,1),SP(:,2),repmat(app.utmZ1,size(SP,1),1));
            if Type1 ~= "SAR"
                T = table(Lat,Lon);
            else
                Spacing = repmat(app.XmEditSpacing.Value,size(Lat,1),1);
                T = table(Lat,Lon,Spacing);
            end
            app.SurveyTypeDesign.SelectedObject
            t = datetime('now','TimeZone','UTC','Format','dMMMy HHmmss');
            pth = strcat(pwd,filesep,"SurveyPoints",filesep,Type1,Type2,"_",string(t),".txt");
            writetable(T,pth,'Delimiter',',');  
        end

        % Callback function
        function AutoReturnSwitch_2ValueChanged(app, event)
            if app.AutoReturnSwitch_2.Value == "AutoReturn"
                clr = [0.00,1.00,0.00];
                sendCMD(app,"AutoReturn,On");
            else
                clr = [1.00,0.00,0.00];
                sendCMD(app,"AutoReturn,Off");
            end 
            app.AReturnLamp.Color = clr;
        end

        % Selection changed function: RadarModeButtonGroup
        function RadarModeButtonGroupSelectionChanged(app, event)
            if app.MONOButton.Value == 1
                En = 0;
                
            elseif app.MIMOButton.Value == 1
                En = 1;
                
            end
            app.Tx2Port.Enable = En;
            app.Tx2Pol.Enable = En;
            app.Rx2Port.Enable = En;
            app.Rx2Pol.Enable = En;
        end

        % Value changed function: nAtts
        function nAttsValueChanged(app, event)
            v = app.nAtts.Value;
            if v == 1
                En2 = 0;
                En3 = 0;
                En4 = 0;
            elseif v ==2
                En2 = 1;
                En3 = 0;
                En4 = 0;  
            elseif v ==3
                En2 = 1;
                En3 = 1;
                En4 = 0; 
            elseif v == 4
                En2 = 1;
                En3 = 1;
                En4 = 1;
            end
            app.Att2.Enable = En2;
            app.Gain2.Enable = En2;
            app.Att3.Enable = En3;
            app.Gain3.Enable = En3;
            app.Att4.Enable = En4;
            app.Gain4.Enable = En4;
        end

        % Value changed function: SETApRES
        function SETApRESValueChanged(app, event)
            % Radio command and ApRES command and ApRES mode (MONO/MIMO)
            md = app.RadarModeButtonGroup.SelectedObject.Text;
            s1 = "#ApRES,Set,"+app.RadarModeButtonGroup.SelectedObject.Text+","; 
            % Number of attenuator
            s2 = string(app.nAtts.Value);
            % Attenuators vector
            s3temp = [app.Att1.Value,app.Att2.Value,app.Att3.Value,app.Att4.Value];
            s3 = ",";
            for i = 1:length(s3temp)
                s3 = s3 + string(s3temp(i)) + ",";
            end
            % Gains vector
            s4temp = [app.Gain1.Value,app.Gain2.Value,app.Gain3.Value,app.Gain4.Value];
            s4 = "";
            for i = 1:length(s4temp)
                s4 = s4 + string(s4temp(i)) + ",";
            end
            % Tx Ports
            s5temp = [0,0,0,0,0,0,0,0];
            s5temp(app.Tx1Port.Value) = 1;
            if md == "MIMO"
                s5temp(app.Tx2Port.Value) = 1;
            end
            s5 = "";
            for i = 1:length(s5temp)
                s5 = s5 + string(s5temp(i)) + ",";
            end
            % Rx Ports
            s6temp = [0,0,0,0,0,0,0,0];
            s6temp(app.Rx1Port.Value) = 1;
            if md == "MIMO"
                s6temp(app.Rx2Port.Value) = 1;
            end
            s6 = "";
            for i = 1:length(s6temp)
                s6 = s6 + string(s6temp(i)) + ",";
            end
            % Tx Polarization
            s7_1 = "T"+string(app.Tx1Port.Value)+app.Tx1Pol.Value;
            s7_2 = "T"+string(app.Tx2Port.Value)+app.Tx2Pol.Value;
            s7_3 = "R"+string(app.Rx1Port.Value)+app.Rx1Pol.Value;
            s7_4 = "R"+string(app.Rx2Port.Value)+app.Rx2Pol.Value;
            if md == "MONO"
                s7 = s7_1+s7_3+",";
            elseif md == "MIMO"
                s7 = s7_1+s7_2+s7_3+s7_4+",";
            end
            % Number of sub bursts
            s8 = string(app.nSubbursts.Value)+",";
            % DownloadFileFlag
            s9 = string(app.DownloadtoARKCheckBox.Value)+",";
            % Prefix name
            s10 = "_"+app.ApRESPreFixName.Value;
            % Final message for radio
            CMD = s1+s2+s3+s4+s5+s6+s7+s8+s9+s10+"$";
            sendCMD(app,CMD);
            app.BURSTApRES.Enable = 1;
            app.QuickBurstButton.Enable = 1;
        end

        % Value changed function: Gain1, Gain2, Gain3, Gain4
        function GainCorrection(app, event)
            v = app.Gain1.Value;
            cond = sum(v==[-14,-4,6]);
            if cond ~= 1
                app.Gain1.Value = -14;
            end
            
            v = app.Gain2.Value;
            cond = sum(v==[-14,-4,6]);
            if cond ~= 1
                app.Gain2.Value = -14;
            end
            
            v = app.Gain3.Value;
            cond = sum(v==[-14,-4,6]);
            if cond ~= 1
                app.Gain3.Value = -14;
            end
            
            v = app.Gain4.Value;
            cond = sum(v==[-14,-4,6]);
            if cond ~= 1
                app.Gain4.Value = -14;
            end
        end

        % Callback function: BURSTApRES, QuickBurstButton
        function BURSTApRESValueChanged(app, event)
            sendCMD(app,"#ApRES,Burst$");
        end

        % Value changed function: ApRESSwitch
        function ApRESSwitchValueChanged(app, event)
            v = app.ApRESSwitch.Value;
            if v == "On"
                sendCMD(app,"#ApRES,On$");
            else
                app.SETApRES.Enable = 0;
                app.BURSTApRES.Enable = 0;
                app.QuickBurstButton.Enable = 0;
                app.ApRESLamp.Color = [1.00,0.00,0.00];
            end
        end

        % Selection changed function: SurveyModeDesign, SurveyTypeDesign
        function SurveyModeDesignSelectionChanged(app, event)
            v1 = app.SurveyTypeDesign.SelectedObject.Text;
            v2 = app.SurveyModeDesign.SelectedObject.Text;
            
            app.AdventureButton.Enable = 1;
            app.PolarimetricButton.Enable = 1;
            app.SARButton.Enable = 1;
            
            app.PointButton.Enable = 1;
            app.ProfileButton.Enable = 1;
            app.AreaButton.Enable = 1;
            
            app.LatEditFirst.Enable = 1;
            app.LonEditFirst.Enable = 1;
            app.LatEditLast.Enable = 1;
            app.LonEditLast.Enable = 1;
            app.LatEditLast.Editable = 1;
            app.LonEditLast.Editable = 1;
            
            app.XmEditSpacing.Enable = 1;
            app.YmEditSpacing.Enable = 1;
            app.XmEditSpacing.Limits = [0.1,inf];
            app.YmEditSpacing.Limits = [0.1,inf];
            app.XmEditSpacing.Value = 0.1;
            app.YmEditSpacing.Value = 0.1;
            %----------
            if v1 == "SAR"
                app.PointButton.Enable = 0; 
                app.AreaButton.Enable = 0; 
                app.ProfileButton.Value = 1;
            elseif v1 == "Adventure" || v1 == "Polarimetric"
                app.XmEditSpacing.Limits = [0.1,inf];
                app.YmEditSpacing.Limits = [0.1,inf];
                app.XmEditSpacing.Value = 30;
                app.YmEditSpacing.Value = 30;
            end
            %----------
            if v2 == "Point"
                if isnan(app.RoverLatDD) || isnan(app.RoverLngDD)
                    app.ProfileButton.Value = 1;
                else
                    app.XmEditSpacing.Enable = 0;
                    app.YmEditSpacing.Enable = 0;
                    app.LatEditFirst.Enable = 0;
                    app.LonEditFirst.Enable = 0;
                    app.AdventureButton.Enable = 1;
                    app.PolarimetricButton.Enable = 1;
                    app.SARButton.Enable = 0;
                end
            elseif v2 == "Profile"
                app.AdventureButton.Enable = 1;
                app.PolarimetricButton.Enable = 1;
                app.SARButton.Enable = 1;
            elseif v2 == "Area"
                app.AdventureButton.Enable = 1;
                app.PolarimetricButton.Enable = 1;
                app.SARButton.Enable = 0;
            end
        end

        % Value changed function: InputFormat
        function InputFormatValueChanged(app, event)
            v = app.InputFormat.Value;
            if isnan(app.RoverLatDD) || isnan(app.RoverLngDD)
                app.InputFormat.Value = "Coordinate";
                v = "Coordinate";
                UpdateRadioStream(app,"ERROR: rover position unknown");
            end
            if v == "Coordinate"
                lbl1_1 = "Lat1";
                lbl1_2 = "Lon1";
                lbl2_1 = "Lat2";
                lbl2_2 = "Lat2";
                dec1 = "%.7f";
                dec2 = "%.7f";
            else
                lbl1_1 = "Dist1";
                lbl1_2 = "Az1";
                lbl2_1 = "Dist2";
                lbl2_2 = "Az2";
                dec1 = "%.2f";
                dec2 = "%.2f";
            end
            if app.StartHereCheckBox.Value == 1
                lbl1_1 = "Lat1";
                lbl1_2 = "Lon1";
                dec1 = "%.7f";
            end
            app.LableLat1.Text = lbl1_1;
            app.LableLon1.Text = lbl1_2;
            app.LableLat2.Text = lbl2_1;
            app.LableLon2.Text = lbl2_2;
            app.LatEditFirst.ValueDisplayFormat = dec1;
            app.LonEditFirst.ValueDisplayFormat = dec1;
            app.LatEditLast.ValueDisplayFormat = dec2;
            app.LonEditLast.ValueDisplayFormat = dec2;
        end

        % Value changed function: StartHereCheckBox
        function StartHereCheckBoxValueChanged(app, event)
            v = app.StartHereCheckBox.Value;
            if ~isnan(app.RoverLatDD) || ~isnan(app.RoverLngDD)
                if v == 1
                    app.LableLat1.Text = "Lat1";
                    app.LableLon1.Text = "Lon1";
                    app.LatEditFirst.ValueDisplayFormat = "%.7f";
                    app.LonEditFirst.ValueDisplayFormat = "%.7f";
                    app.LatEditFirst.Value = app.RoverLatDD;
                    app.LonEditFirst.Value = app.RoverLngDD;
                end 
            else
                app.StartHereCheckBox.Value = 0;
                UpdateRadioStream(app,"ERROR: rover position unknown");
            end
        end

        % Button pushed function: testonButton
        function testonButtonPushed(app, event)
            sendCMD(app,"#ApRES,TestModeOn$");
        end

        % Button pushed function: testoffButton
        function testoffButtonPushed(app, event)
            sendCMD(app,"#ApRES,TestModeOff$");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.ARKMenu = uimenu(app.Menu);
            app.ARKMenu.Text = 'ARK';
            app.RestartMenu = uimenu(app.ARKMenu);
            app.RestartMenu.MenuSelectedFcn = createCallbackFcn(app, @RestartMenuSelected, true);
            app.RestartMenu.Text = 'Restart';
            app.ShutdownMenu = uimenu(app.ARKMenu);
            app.ShutdownMenu.MenuSelectedFcn = createCallbackFcn(app, @ShutdownMenuSelected, true);
            app.ShutdownMenu.Text = 'Shutdown';
            app.EXITMenu = uimenu(app.Menu);
            app.EXITMenu.MenuSelectedFcn = createCallbackFcn(app, @ClosetheappButtonPushed, true);
            app.EXITMenu.Separator = 'on';
            app.EXITMenu.Text = 'EXIT';
            app.General = uipanel(app.UIFigure);
            app.EMERGENCYSTOPButton = uibutton(app.General, 'push');
            app.EMERGENCYSTOPButton.ButtonPushedFcn = createCallbackFcn(app, @EMERGENCYSTOPButtonPushed, true);
            app.EMERGENCYSTOPButton.Text = 'EMERGENCY STOP';
            app.RoverNowText = uitextarea(app.General);
            app.RoverNowText.Value = {'                    GPS'; 'Time: ()'; 'xR. [utm]:'; 'yR. [utm]:'; 'zR. [msl]:'; 'cR. [deg]:'; 'xH. [utm]:'; 'yH. [utm]:'; 'D.2Home [m]:'};
            app.Panel_8 = uipanel(app.General);
            app.velLIN = uieditfield(app.Panel_8, 'numeric');
            app.velLIN.Editable = 'off';
            app.velANG = uieditfield(app.Panel_8, 'numeric');
            app.velANG.Editable = 'off';
            app.BatteryLamp = uilamp(app.Panel_8);
            app.GPSSpeedmsGaugeLabel = uilabel(app.Panel_8);
            app.GPSSpeedmsGaugeLabel.Text = 'GPS Speed [m/s]';
            app.GPSSpeedmsGauge = uigauge(app.Panel_8, 'semicircular');
            app.GPSSpeedmsGauge.Limits = [0 3];
            app.BatteryVGaugeLabel = uilabel(app.Panel_8);
            app.BatteryVGaugeLabel.Text = 'Battery [V]';
            app.BatteryVGauge = uigauge(app.Panel_8, 'circular');
            app.BatteryVGauge.Limits = [47 57];
            app.BatteryVGauge.MajorTicks = [49 51 53 55 57];
            app.GPSspeedValue = uieditfield(app.Panel_8, 'numeric');
            app.GPSspeedValue.Editable = 'off';
            app.BatteryLeftH = uieditfield(app.Panel_8, 'numeric');
            app.BatteryLeftH.Editable = 'off';
            app.UpdateHomeButton = uibutton(app.General, 'push');
            app.UpdateHomeButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateHome_Pushed, true);
            app.UpdateHomeButton.Text = 'Update Home';
            app.SwitchGPSButton = uibutton(app.General, 'push');
            app.SwitchGPSButton.ButtonPushedFcn = createCallbackFcn(app, @SwitchGPSButtonPushed, true);
            app.SwitchGPSButton.Text = 'Switch GPS';
            app.QuickBurstButton = uibutton(app.General, 'push');
            app.QuickBurstButton.ButtonPushedFcn = createCallbackFcn(app, @BURSTApRESValueChanged, true);
            app.QuickBurstButton.Enable = 'off';
            app.QuickBurstButton.Text = 'Burst';
            app.ErrorLampLabel = uilabel(app.General);
            app.ErrorLampLabel.Text = 'Error';
            app.ErrorLamp = uilamp(app.General);
            app.ErrorLamp.Enable = 'off';
            app.RadioLampLabel = uilabel(app.General);
            app.RadioLampLabel.Text = 'Radio';
            app.RadioLamp = uilamp(app.General);
            app.HandBreakLampLabel = uilabel(app.General);
            app.HandBreakLampLabel.Text = 'Hand Break';
            app.HandBreakLamp = uilamp(app.General);
            app.IgnoreInstLampLabel = uilabel(app.General);
            app.IgnoreInstLampLabel.Text = 'Ignore Inst.';
            app.IgnoreInstLamp = uilamp(app.General);
            app.ApRESLampLabel = uilabel(app.General);
            app.ApRESLampLabel.Text = 'ApRES';
            app.ApRESLamp = uilamp(app.General);
            app.APilotLampLabel = uilabel(app.General);
            app.APilotLampLabel.Text = 'A. Pilot';
            app.APilotLamp = uilamp(app.General);
            app.LBMLampLabel = uilabel(app.General);
            app.LBMLampLabel.Text = 'L.B.M';
            app.LBMLamp = uilamp(app.General);
            app.Panel_12 = uipanel(app.General);
            app.DriveModeSwitch = uiswitch(app.Panel_12, 'slider');
            app.DriveModeSwitch.Items = {'Manual', 'Auto'};
            app.DriveModeSwitch.ValueChangedFcn = createCallbackFcn(app, @DriveModeSwitchValueChanged, true);
            app.DriveModeSwitch.Value = 'Manual';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.RoverSettingTab = uitab(app.TabGroup);
            app.RoverSettingTab.Title = 'Rover Setting';
            app.EXITButtonPushed = uibutton(app.RoverSettingTab, 'push');
            app.EXITButtonPushed.ButtonPushedFcn = createCallbackFcn(app, @ClosetheappButtonPushed, true);
            app.EXITButtonPushed.Text = 'EXIT';
            app.ARK_PowerPushed = uibutton(app.RoverSettingTab, 'push');
            app.ARK_PowerPushed.ButtonPushedFcn = createCallbackFcn(app, @ARK_PowerPushedPushed, true);
            app.ARK_PowerPushed.Text = 'ARK';
            app.RoverParametersPanel = uipanel(app.RoverSettingTab);
            app.RoverParametersPanel.Title = 'Rover Parameters';
            app.TelemetryIntervalsLabel = uilabel(app.RoverParametersPanel);
            app.TelemetryIntervalsLabel.Text = 'Telemetry Interval [s]';
            app.TelemetryIntervalsEditField = uieditfield(app.RoverParametersPanel, 'numeric');
            app.TelemetryIntervalsEditField.Limits = [0.1 599.9];
            app.TelemetryIntervalsEditField.ValueChangedFcn = createCallbackFcn(app, @TelemetryIntervals_Changed, true);
            app.TelemetryIntervalsEditField.Value = 5;
            app.AutoMaxSpeedLabel = uilabel(app.RoverParametersPanel);
            app.AutoMaxSpeedLabel.Text = 'Auto Max Speed [%]';
            app.AutoMaxSpeedEditField = uieditfield(app.RoverParametersPanel, 'numeric');
            app.AutoMaxSpeedEditField.Limits = [25 60];
            app.AutoMaxSpeedEditField.ValueChangedFcn = createCallbackFcn(app, @AutonomousMaxSpeed_Changed, true);
            app.AutoMaxSpeedEditField.Value = 40;
            app.SpeedPCoeffEditFieldLabel = uilabel(app.RoverParametersPanel);
            app.SpeedPCoeffEditFieldLabel.Text = 'Speed P. Coeff.';
            app.SpeedPCoeffEditField = uieditfield(app.RoverParametersPanel, 'numeric');
            app.SpeedPCoeffEditField.ValueChangedFcn = createCallbackFcn(app, @PIAD_Coefficients_Changed, true);
            app.SpeedPCoeffEditField.Value = 0.5;
            app.VFactorEditField_2Label = uilabel(app.RoverParametersPanel);
            app.VFactorEditField_2Label.Enable = 'off';
            app.VFactorEditField_2Label.Text = 'V Factor';
            app.VFactorEditField = uieditfield(app.RoverParametersPanel, 'numeric');
            app.VFactorEditField.Limits = [1500 1717];
            app.VFactorEditField.Editable = 'off';
            app.VFactorEditField.Enable = 'off';
            app.VFactorEditField.Value = 1717;
            app.SARMaxSpeedLabel = uilabel(app.RoverParametersPanel);
            app.SARMaxSpeedLabel.Text = 'SAR Max Speed [%]';
            app.SARMaxSpeedEditField = uieditfield(app.RoverParametersPanel, 'numeric');
            app.SARMaxSpeedEditField.Limits = [5 60];
            app.SARMaxSpeedEditField.ValueChangedFcn = createCallbackFcn(app, @SARMaxSpeedEditFieldValueChanged, true);
            app.SARMaxSpeedEditField.Value = 25;
            app.NavigationRadiusmEditFieldLabel = uilabel(app.RoverParametersPanel);
            app.NavigationRadiusmEditFieldLabel.Text = 'Navigation Radius [m]';
            app.NavigationRadiusmEditField = uieditfield(app.RoverParametersPanel, 'numeric');
            app.NavigationRadiusmEditField.Limits = [0.1 254.9];
            app.NavigationRadiusmEditField.ValueChangedFcn = createCallbackFcn(app, @NavigationRadius_Changed, true);
            app.NavigationRadiusmEditField.Value = 2;
            app.WaypointRadiusmEditFieldLabel = uilabel(app.RoverParametersPanel);
            app.WaypointRadiusmEditFieldLabel.Text = 'Waypoint Radius [m]';
            app.WaypointRadiusmEditField = uieditfield(app.RoverParametersPanel, 'numeric');
            app.WaypointRadiusmEditField.Limits = [0 100];
            app.WaypointRadiusmEditField.ValueChangedFcn = createCallbackFcn(app, @WaypointRadius_Changed, true);
            app.WaypointRadiusmEditField.Value = 3;
            app.PCoefficientEditFieldLabel = uilabel(app.RoverParametersPanel);
            app.PCoefficientEditFieldLabel.Text = 'P Coefficient';
            app.PCoefficientEditField = uieditfield(app.RoverParametersPanel, 'numeric');
            app.PCoefficientEditField.ValueChangedFcn = createCallbackFcn(app, @PIAD_Coefficients_Changed, true);
            app.PCoefficientEditField.Value = 6;
            app.ICoefficientEditFieldLabel = uilabel(app.RoverParametersPanel);
            app.ICoefficientEditFieldLabel.Text = 'I Coefficient';
            app.ICoefficientEditField = uieditfield(app.RoverParametersPanel, 'numeric');
            app.ICoefficientEditField.ValueChangedFcn = createCallbackFcn(app, @PIAD_Coefficients_Changed, true);
            app.WFactorEditFieldLabel = uilabel(app.RoverParametersPanel);
            app.WFactorEditFieldLabel.Enable = 'off';
            app.WFactorEditFieldLabel.Text = 'W Factor';
            app.WFactorEditField = uieditfield(app.RoverParametersPanel, 'numeric');
            app.WFactorEditField.Limits = [1 100];
            app.WFactorEditField.Editable = 'off';
            app.WFactorEditField.Enable = 'off';
            app.WFactorEditField.Value = 1;
            app.WOffsetEditFieldLabel = uilabel(app.RoverParametersPanel);
            app.WOffsetEditFieldLabel.Enable = 'off';
            app.WOffsetEditFieldLabel.Text = 'W Offset';
            app.WOffsetEditField = uieditfield(app.RoverParametersPanel, 'numeric');
            app.WOffsetEditField.Limits = [-254.9 254.9];
            app.WOffsetEditField.ValueChangedFcn = createCallbackFcn(app, @WOffset_Changed, true);
            app.WOffsetEditField.Editable = 'off';
            app.WOffsetEditField.Enable = 'off';
            app.RoverPositionErrorEditFieldLabel = uilabel(app.RoverParametersPanel);
            app.RoverPositionErrorEditFieldLabel.Text = 'RoverPosition Error';
            app.RoverPositionErrorEditField = uieditfield(app.RoverParametersPanel, 'numeric');
            app.RoverPositionErrorEditField.ValueChangedFcn = createCallbackFcn(app, @PIAD_Coefficients_Changed, true);
            app.RoverPositionErrorEditField.Value = 0.1;
            app.RadioPortDropDown = uidropdown(app.RoverSettingTab);
            app.RadioPortDropDown.Items = {'Select a RADIO port', 'Select a RADIO port2', ''};
            app.RadioPortDropDown.ValueChangedFcn = createCallbackFcn(app, @RadioPortDropDownValueChanged, true);
            app.RadioPortDropDown.Value = 'Select a RADIO port';
            app.Panel_11 = uipanel(app.RoverSettingTab);
            app.RADIOSwitchLabel = uilabel(app.Panel_11);
            app.RADIOSwitchLabel.Text = 'RADIO';
            app.RADIOSwitch = uiswitch(app.Panel_11, 'toggle');
            app.RADIOSwitch.Items = {'Disconnect', 'Connect'};
            app.RADIOSwitch.ValueChangedFcn = createCallbackFcn(app, @RADIOSwitchChanged, true);
            app.RADIOSwitch.Value = 'Disconnect';
            app.Panel_9 = uipanel(app.RoverSettingTab);
            app.HandBreakLabel = uilabel(app.Panel_9);
            app.HandBreakLabel.Text = 'Hand Break';
            app.HandBreakSwitch_1 = uiswitch(app.Panel_9, 'toggle');
            app.HandBreakSwitch_1.Items = {'Down', 'Up'};
            app.HandBreakSwitch_1.ValueChangedFcn = createCallbackFcn(app, @HandBreakSwitch_1_Changed, true);
            app.HandBreakSwitch_1.Value = 'Up';
            app.Panel_10 = uipanel(app.RoverSettingTab);
            app.LowBatteryModeSwitchLabel = uilabel(app.Panel_10);
            app.LowBatteryModeSwitchLabel.Text = 'LowBattery Mode';
            app.LowBatteryModeSwitch = uiswitch(app.Panel_10, 'toggle');
            app.LowBatteryModeSwitch.ValueChangedFcn = createCallbackFcn(app, @LowBatteryModeSwitch_Changed, true);
            app.LowBatteryModeSwitch.Value = 'On';
            app.IgnoreInstrumentSwitchLabel = uilabel(app.Panel_10);
            app.IgnoreInstrumentSwitchLabel.Text = 'Ignore Instrument';
            app.IgnoreInstrumentSwitch = uiswitch(app.Panel_10, 'toggle');
            app.IgnoreInstrumentSwitch.ValueChangedFcn = createCallbackFcn(app, @IgnoreInstrumentSwitch_Changed, true);
            app.SettingsQueryButton = uibutton(app.RoverSettingTab, 'push');
            app.SettingsQueryButton.ButtonPushedFcn = createCallbackFcn(app, @SettingsQuery_Pushed, true);
            app.SettingsQueryButton.Text = 'Settings Query';
            app.DriverScreenTab = uitab(app.TabGroup);
            app.DriverScreenTab.Title = 'Driver Screen';
            app.UIax_ManualDriveMap = uiaxes(app.DriverScreenTab);
            title(app.UIax_ManualDriveMap, 'Title')
            zlabel(app.UIax_ManualDriveMap, 'Z')
            app.Panel_2 = uipanel(app.DriverScreenTab);
            app.ReverseSwitchLabel = uilabel(app.Panel_2);
            app.ReverseSwitchLabel.Text = 'Reverse';
            app.ReverseSwitch = uiswitch(app.Panel_2, 'toggle');
            app.ReverseSwitch.ValueChangedFcn = createCallbackFcn(app, @ReverseGearSwitch, true);
            app.HandBreakSwitch_2Label = uilabel(app.Panel_2);
            app.HandBreakSwitch_2Label.Text = 'Hand Break';
            app.HandBreakSwitch_2 = uiswitch(app.Panel_2, 'toggle');
            app.HandBreakSwitch_2.Items = {'Down', 'Up'};
            app.HandBreakSwitch_2.ValueChangedFcn = createCallbackFcn(app, @HandBreakSwitch_2_Changed, true);
            app.HandBreakSwitch_2.Value = 'Up';
            app.ComeHomeSwitch_2Label = uilabel(app.Panel_2);
            app.ComeHomeSwitch_2Label.Text = 'Come Home';
            app.ComeHomeSwitch = uiswitch(app.Panel_2, 'toggle');
            app.ComeHomeSwitch.ValueChangedFcn = createCallbackFcn(app, @ComeHomeSwitch_Changed, true);
            app.Panel_3 = uipanel(app.DriverScreenTab);
            app.VSlider = uislider(app.Panel_3);
            app.VSlider.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.VSlider.MajorTickLabels = {'0', '10%', '20%', '30%', '40%', '50%', '60%', '70%', '80%', '90%', 'WTF!'};
            app.VSlider.ValueChangedFcn = createCallbackFcn(app, @VWslider_Moved, true);
            app.Panel_4 = uipanel(app.DriverScreenTab);
            app.WSlider = uislider(app.Panel_4);
            app.WSlider.Limits = [-140 140];
            app.WSlider.MajorTicks = [-140 -105 -70 -35 0 35 70 105 140];
            app.WSlider.MajorTickLabels = {'L', '', '', '', '', '', '', '', 'R'};
            app.WSlider.ValueChangedFcn = createCallbackFcn(app, @VWslider_Moved, true);
            app.WSlider.MinorTicks = [];
            app.WSlider.Enable = 'off';
            app.StraightButton = uibutton(app.Panel_4, 'push');
            app.StraightButton.ButtonPushedFcn = createCallbackFcn(app, @StraightButton_Pushed, true);
            app.StraightButton.Text = 'S';
            app.SpeedUp10Button = uibutton(app.DriverScreenTab, 'push');
            app.SpeedUp10Button.ButtonPushedFcn = createCallbackFcn(app, @SpeedUp10ButtonPushed, true);
            app.SpeedUp10Button.Text = 'Speed Up (10%)';
            app.SlowDown10Button = uibutton(app.DriverScreenTab, 'push');
            app.SlowDown10Button.ButtonPushedFcn = createCallbackFcn(app, @SlowDown10ButtonPushed, true);
            app.SlowDown10Button.Text = 'Slow Down (10%)';
            app.Left20Button = uibutton(app.DriverScreenTab, 'push');
            app.Left20Button.ButtonPushedFcn = createCallbackFcn(app, @Left20ButtonPushed, true);
            app.Left20Button.Enable = 'off';
            app.Left20Button.Text = 'Left (20%)';
            app.Right20Button = uibutton(app.DriverScreenTab, 'push');
            app.Right20Button.ButtonPushedFcn = createCallbackFcn(app, @Right20ButtonPushed, true);
            app.Right20Button.Enable = 'off';
            app.Right20Button.Text = 'Right (20%)';
            app.STOPButton = uibutton(app.DriverScreenTab, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = '!!!STOP!!!';
            app.StraightButton2 = uibutton(app.DriverScreenTab, 'push');
            app.StraightButton2.ButtonPushedFcn = createCallbackFcn(app, @StraightButton_Pushed, true);
            app.StraightButton2.Text = 'Staright';
            app.vltFL = uieditfield(app.DriverScreenTab, 'numeric');
            app.vltFL.Editable = 'off';
            app.vltFL.Enable = 'off';
            app.vltFL.Visible = 'off';
            app.vltBL = uieditfield(app.DriverScreenTab, 'numeric');
            app.vltBL.Editable = 'off';
            app.vltBL.Enable = 'off';
            app.vltBL.Visible = 'off';
            app.vltFR = uieditfield(app.DriverScreenTab, 'numeric');
            app.vltFR.Editable = 'off';
            app.vltFR.Enable = 'off';
            app.vltFR.Visible = 'off';
            app.vltBR = uieditfield(app.DriverScreenTab, 'numeric');
            app.vltBR.Editable = 'off';
            app.vltBR.Enable = 'off';
            app.vltBR.Visible = 'off';
            app.AutoDriveTab = uitab(app.TabGroup);
            app.AutoDriveTab.Title = 'Auto Drive';
            app.XNowLabel = uilabel(app.AutoDriveTab);
            app.XNowLabel.Text = 'X Now';
            app.X_RoverNow_UTM = uieditfield(app.AutoDriveTab, 'numeric');
            app.X_RoverNow_UTM.ValueDisplayFormat = '%.5f';
            app.X_RoverNow_UTM.Editable = 'off';
            app.YNowLabel = uilabel(app.AutoDriveTab);
            app.YNowLabel.Text = 'Y Now';
            app.Y_RoverNow_UTM = uieditfield(app.AutoDriveTab, 'numeric');
            app.Y_RoverNow_UTM.ValueDisplayFormat = '%.5f';
            app.Y_RoverNow_UTM.Editable = 'off';
            app.XGoalEditFieldLabel = uilabel(app.AutoDriveTab);
            app.XGoalEditFieldLabel.Text = 'X Goal';
            app.XGoalEditField = uieditfield(app.AutoDriveTab, 'numeric');
            app.XGoalEditField.ValueDisplayFormat = '%.5f';
            app.XGoalEditField.Editable = 'off';
            app.YGoalEditFieldLabel = uilabel(app.AutoDriveTab);
            app.YGoalEditFieldLabel.Text = 'Y Goal';
            app.YGoalEditField = uieditfield(app.AutoDriveTab, 'numeric');
            app.YGoalEditField.ValueDisplayFormat = '%.5f';
            app.YGoalEditField.Editable = 'off';
            app.D2GoalEditFieldLabel = uilabel(app.AutoDriveTab);
            app.D2GoalEditFieldLabel.Text = 'D2Goal';
            app.D2GoalEditField = uieditfield(app.AutoDriveTab, 'numeric');
            app.D2GoalEditField.ValueDisplayFormat = '%.2f';
            app.D2GoalEditField.Editable = 'off';
            app.Panel_6 = uipanel(app.AutoDriveTab);
            app.UIax_SurveyMap = uiaxes(app.Panel_6);
            xlabel(app.UIax_SurveyMap, 'Easting [m]')
            ylabel(app.UIax_SurveyMap, 'Northing [m]')
            app.B2GoalEditFieldLabel = uilabel(app.AutoDriveTab);
            app.B2GoalEditFieldLabel.Text = 'B2Goal';
            app.B2GoalEditField = uieditfield(app.AutoDriveTab, 'numeric');
            app.B2GoalEditField.ValueDisplayFormat = '%.2f';
            app.B2GoalEditField.Editable = 'off';
            app.PointsEditFieldLabel_2 = uilabel(app.AutoDriveTab);
            app.PointsEditFieldLabel_2.Text = '#Points';
            app.NsurveyPoints_2 = uieditfield(app.AutoDriveTab, 'numeric');
            app.NsurveyPoints_2.Limits = [0 Inf];
            app.NsurveyPoints_2.Editable = 'off';
            app.SurveykmLabel_2 = uilabel(app.AutoDriveTab);
            app.SurveykmLabel_2.Text = 'Survey [km]';
            app.SurveyKMEdit_2 = uieditfield(app.AutoDriveTab, 'numeric');
            app.SurveyKMEdit_2.Limits = [0 Inf];
            app.SurveyKMEdit_2.Editable = 'off';
            app.MaxD2HLabel_2 = uilabel(app.AutoDriveTab);
            app.MaxD2HLabel_2.Text = 'Max D2H';
            app.LP2HEdit_2 = uieditfield(app.AutoDriveTab, 'numeric');
            app.LP2HEdit_2.Limits = [0 Inf];
            app.LP2HEdit_2.Editable = 'off';
            app.CurrentpointLabel = uilabel(app.AutoDriveTab);
            app.CurrentpointLabel.Text = '#Current point';
            app.nCurrentPoint = uieditfield(app.AutoDriveTab, 'numeric');
            app.nCurrentPoint.Limits = [0 Inf];
            app.nCurrentPoint.Editable = 'off';
            app.RefDstEditFieldLabel = uilabel(app.AutoDriveTab);
            app.RefDstEditFieldLabel.Text = 'RefDst';
            app.RefDstEditField = uieditfield(app.AutoDriveTab, 'numeric');
            app.RefDstEditField.ValueDisplayFormat = '%.2f';
            app.RefDstEditField.Editable = 'off';
            app.DesignasurveyTab = uitab(app.TabGroup);
            app.DesignasurveyTab.Title = 'Design a survey';
            app.SimAx = uiaxes(app.DesignasurveyTab);
            app.SimAx.XTickLabel = '';
            app.SimAx.YTickLabel = '';
            app.SimulateButton = uibutton(app.DesignasurveyTab, 'state');
            app.SimulateButton.ValueChangedFcn = createCallbackFcn(app, @SimulateTheSurvey_Pushed, true);
            app.SimulateButton.Enable = 'off';
            app.SimulateButton.Text = 'Simulate';
            app.SENDTOARKButton = uibutton(app.DesignasurveyTab, 'push');
            app.SENDTOARKButton.ButtonPushedFcn = createCallbackFcn(app, @SendSimulationToARK_Pushed, true);
            app.SENDTOARKButton.Enable = 'off';
            app.SENDTOARKButton.Text = 'SEND TO ARK';
            app.LoadButton = uibutton(app.DesignasurveyTab, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadCoordinates_Pushed, true);
            app.LoadButton.Text = 'Load';
            app.FirstpointPanel = uipanel(app.DesignasurveyTab);
            app.FirstpointPanel.Title = 'First point';
            app.LatEditFirst = uieditfield(app.FirstpointPanel, 'numeric');
            app.LatEditFirst.ValueDisplayFormat = '%.7f';
            app.LonEditFirst = uieditfield(app.FirstpointPanel, 'numeric');
            app.LonEditFirst.ValueDisplayFormat = '%.7f';
            app.LableLat1 = uilabel(app.FirstpointPanel);
            app.LableLat1.Text = 'Lat1';
            app.LableLon1 = uilabel(app.FirstpointPanel);
            app.LableLon1.Text = 'Lon1';
            app.LonEditLast = uieditfield(app.FirstpointPanel, 'numeric');
            app.LonEditLast.ValueDisplayFormat = '%.7f';
            app.LatEditLast = uieditfield(app.FirstpointPanel, 'numeric');
            app.LatEditLast.ValueDisplayFormat = '%.7f';
            app.XmEditSpacing = uieditfield(app.FirstpointPanel, 'numeric');
            app.XmEditSpacing.Limits = [0.1 Inf];
            app.XmEditSpacing.ValueChangedFcn = createCallbackFcn(app, @SurveyXspacing, true);
            app.XmEditSpacing.Value = 30;
            app.YmEditSpacing = uieditfield(app.FirstpointPanel, 'numeric');
            app.YmEditSpacing.Limits = [0.1 Inf];
            app.YmEditSpacing.ValueChangedFcn = createCallbackFcn(app, @SurveyYspacing, true);
            app.YmEditSpacing.Value = 30;
            app.LabledX = uilabel(app.FirstpointPanel);
            app.LabledX.Text = 'dX [m]';
            app.LabledY = uilabel(app.FirstpointPanel);
            app.LabledY.Text = 'dY [m]';
            app.LableLat2 = uilabel(app.FirstpointPanel);
            app.LableLat2.Text = 'Lat2';
            app.LableLon2 = uilabel(app.FirstpointPanel);
            app.LableLon2.Text = 'Lon2';
            app.ClearButton = uibutton(app.DesignasurveyTab, 'state');
            app.ClearButton.ValueChangedFcn = createCallbackFcn(app, @DeleteSimulation_Pushed, true);
            app.ClearButton.Text = 'Clear';
            app.SAVEButton = uibutton(app.DesignasurveyTab, 'push');
            app.SAVEButton.ButtonPushedFcn = createCallbackFcn(app, @SAVEButtonPushed, true);
            app.SAVEButton.Enable = 'off';
            app.SAVEButton.Text = 'SAVE';
            app.PointsEditFieldLabel = uilabel(app.DesignasurveyTab);
            app.PointsEditFieldLabel.Text = '#Points';
            app.NsurveyPoints = uieditfield(app.DesignasurveyTab, 'numeric');
            app.NsurveyPoints.Limits = [0 Inf];
            app.NsurveyPoints.Editable = 'off';
            app.SurveykmLabel = uilabel(app.DesignasurveyTab);
            app.SurveykmLabel.Text = 'Survey [km]';
            app.SurveyKMEdit = uieditfield(app.DesignasurveyTab, 'numeric');
            app.SurveyKMEdit.Limits = [0 Inf];
            app.SurveyKMEdit.Editable = 'off';
            app.MaxD2HLabel = uilabel(app.DesignasurveyTab);
            app.MaxD2HLabel.Text = 'Max D2H';
            app.LP2HEdit = uieditfield(app.DesignasurveyTab, 'numeric');
            app.LP2HEdit.Limits = [0 Inf];
            app.LP2HEdit.Editable = 'off';
            app.InputFormat = uidropdown(app.DesignasurveyTab);
            app.InputFormat.Items = {'Coordinate', 'Distance/Azimuth'};
            app.InputFormat.ValueChangedFcn = createCallbackFcn(app, @InputFormatValueChanged, true);
            app.InputFormat.Value = 'Coordinate';
            app.SurveyTypeDesign = uibuttongroup(app.DesignasurveyTab);
            app.SurveyTypeDesign.SelectionChangedFcn = createCallbackFcn(app, @SurveyModeDesignSelectionChanged, true);
            app.AdventureButton = uiradiobutton(app.SurveyTypeDesign);
            app.AdventureButton.Text = 'Adventure';
            app.AdventureButton.Value = true;
            app.PolarimetricButton = uiradiobutton(app.SurveyTypeDesign);
            app.PolarimetricButton.Text = 'Polarimetric';
            app.SARButton = uiradiobutton(app.SurveyTypeDesign);
            app.SARButton.Text = 'SAR';
            app.SurveyModeDesign = uibuttongroup(app.DesignasurveyTab);
            app.SurveyModeDesign.SelectionChangedFcn = createCallbackFcn(app, @SurveyModeDesignSelectionChanged, true);
            app.ProfileButton = uiradiobutton(app.SurveyModeDesign);
            app.ProfileButton.Text = 'Profile';
            app.ProfileButton.Value = true;
            app.AreaButton = uiradiobutton(app.SurveyModeDesign);
            app.AreaButton.Text = 'Area';
            app.PointButton = uiradiobutton(app.SurveyModeDesign);
            app.PointButton.Text = 'Point';
            app.UpdateFigureButton = uibutton(app.DesignasurveyTab, 'push');
            app.UpdateFigureButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateFigureButtonPushed, true);
            app.UpdateFigureButton.Text = 'Update Figure';
            app.StartHereCheckBox = uicheckbox(app.DesignasurveyTab);
            app.StartHereCheckBox.ValueChangedFcn = createCallbackFcn(app, @StartHereCheckBoxValueChanged, true);
            app.StartHereCheckBox.Text = 'Start Here';
            app.RadarTab = uitab(app.TabGroup);
            app.RadarTab.Title = 'Radar';
            app.RadarModeButtonGroup = uibuttongroup(app.RadarTab);
            app.RadarModeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @RadarModeButtonGroupSelectionChanged, true);
            app.RadarModeButtonGroup.Title = 'Radar Mode';
            app.MONOButton = uiradiobutton(app.RadarModeButtonGroup);
            app.MONOButton.Text = 'MONO';
            app.MONOButton.Value = true;
            app.MIMOButton = uiradiobutton(app.RadarModeButtonGroup);
            app.MIMOButton.Text = 'MIMO';
            app.AntennaPortOrientationPanel = uipanel(app.RadarTab);
            app.AntennaPortOrientationPanel.Title = 'Antenna (Port & Orientation)';
            app.Tx1Pol = uieditfield(app.AntennaPortOrientationPanel, 'text');
            app.Tx1Pol.Value = 'H';
            app.Rx1Pol = uieditfield(app.AntennaPortOrientationPanel, 'text');
            app.Rx1Pol.Value = 'H';
            app.Tx2Pol = uieditfield(app.AntennaPortOrientationPanel, 'text');
            app.Tx2Pol.Enable = 'off';
            app.Tx2Pol.Value = 'V';
            app.Rx2Pol = uieditfield(app.AntennaPortOrientationPanel, 'text');
            app.Rx2Pol.Enable = 'off';
            app.Rx2Pol.Value = 'V';
            app.Tx1EditFieldLabel = uilabel(app.AntennaPortOrientationPanel);
            app.Tx1EditFieldLabel.Text = 'Tx 1 ';
            app.Tx1Port = uieditfield(app.AntennaPortOrientationPanel, 'numeric');
            app.Tx1Port.Limits = [1 8];
            app.Tx1Port.Value = 1;
            app.Rx1EditFieldLabel = uilabel(app.AntennaPortOrientationPanel);
            app.Rx1EditFieldLabel.Text = 'Rx 1 ';
            app.Rx1Port = uieditfield(app.AntennaPortOrientationPanel, 'numeric');
            app.Rx1Port.Limits = [1 8];
            app.Rx1Port.Value = 1;
            app.Tx2EditFieldLabel = uilabel(app.AntennaPortOrientationPanel);
            app.Tx2EditFieldLabel.Text = 'Tx 2 ';
            app.Tx2Port = uieditfield(app.AntennaPortOrientationPanel, 'numeric');
            app.Tx2Port.Limits = [1 8];
            app.Tx2Port.Enable = 'off';
            app.Tx2Port.Value = 2;
            app.Rx2EditFieldLabel = uilabel(app.AntennaPortOrientationPanel);
            app.Rx2EditFieldLabel.Text = 'Rx 2 ';
            app.Rx2Port = uieditfield(app.AntennaPortOrientationPanel, 'numeric');
            app.Rx2Port.Limits = [1 8];
            app.Rx2Port.Enable = 'off';
            app.Rx2Port.Value = 2;
            app.BURSTApRES = uibutton(app.RadarTab, 'state');
            app.BURSTApRES.ValueChangedFcn = createCallbackFcn(app, @BURSTApRESValueChanged, true);
            app.BURSTApRES.Enable = 'off';
            app.BURSTApRES.Text = 'BURST';
            app.AttenuatorSettingPanel = uipanel(app.RadarTab);
            app.AttenuatorSettingPanel.Title = 'Attenuator Setting';
            app.Att2 = uieditfield(app.AttenuatorSettingPanel, 'numeric');
            app.Att2.Limits = [0 31];
            app.Att2.Enable = 'off';
            app.Att3 = uieditfield(app.AttenuatorSettingPanel, 'numeric');
            app.Att3.Limits = [0 31];
            app.Att3.Enable = 'off';
            app.Gain2 = uieditfield(app.AttenuatorSettingPanel, 'numeric');
            app.Gain2.Limits = [-14 6];
            app.Gain2.ValueChangedFcn = createCallbackFcn(app, @GainCorrection, true);
            app.Gain2.Enable = 'off';
            app.Gain2.Value = -14;
            app.Gain3 = uieditfield(app.AttenuatorSettingPanel, 'numeric');
            app.Gain3.Limits = [-14 6];
            app.Gain3.ValueChangedFcn = createCallbackFcn(app, @GainCorrection, true);
            app.Gain3.Enable = 'off';
            app.Gain3.Value = -14;
            app.NumberofAttenuatorEditFieldLabel = uilabel(app.AttenuatorSettingPanel);
            app.NumberofAttenuatorEditFieldLabel.Text = 'Number of Attenuator';
            app.nAtts = uieditfield(app.AttenuatorSettingPanel, 'numeric');
            app.nAtts.Limits = [1 4];
            app.nAtts.ValueChangedFcn = createCallbackFcn(app, @nAttsValueChanged, true);
            app.nAtts.Value = 1;
            app.AttenuatorValues031Label = uilabel(app.AttenuatorSettingPanel);
            app.AttenuatorValues031Label.Text = 'Attenuator Value(s) [0:31]';
            app.Att1 = uieditfield(app.AttenuatorSettingPanel, 'numeric');
            app.Att1.Limits = [0 31];
            app.Att1.Value = 20;
            app.GainValues1446Label = uilabel(app.AttenuatorSettingPanel);
            app.GainValues1446Label.Text = 'Gain Value(s) [-14,-4,6]';
            app.Gain1 = uieditfield(app.AttenuatorSettingPanel, 'numeric');
            app.Gain1.Limits = [-14 6];
            app.Gain1.ValueChangedFcn = createCallbackFcn(app, @GainCorrection, true);
            app.Gain1.Value = -4;
            app.Gain4 = uieditfield(app.AttenuatorSettingPanel, 'numeric');
            app.Gain4.Limits = [-14 6];
            app.Gain4.ValueChangedFcn = createCallbackFcn(app, @GainCorrection, true);
            app.Gain4.Enable = 'off';
            app.Gain4.Value = -14;
            app.Att4 = uieditfield(app.AttenuatorSettingPanel, 'numeric');
            app.Att4.Limits = [0 31];
            app.Att4.Enable = 'off';
            app.SETApRES = uibutton(app.RadarTab, 'state');
            app.SETApRES.ValueChangedFcn = createCallbackFcn(app, @SETApRESValueChanged, true);
            app.SETApRES.Text = 'SET';
            app.SubBurstsPanel = uipanel(app.RadarTab);
            app.SubBurstsPanel.Title = 'Sub-Bursts';
            app.NumberofSubBurstsEditFieldLabel = uilabel(app.SubBurstsPanel);
            app.NumberofSubBurstsEditFieldLabel.Text = 'Number of SubBursts';
            app.nSubbursts = uieditfield(app.SubBurstsPanel, 'numeric');
            app.nSubbursts.Limits = [1 2001];
            app.nSubbursts.Value = 10;
            app.testonButton = uibutton(app.SubBurstsPanel, 'push');
            app.testonButton.ButtonPushedFcn = createCallbackFcn(app, @testonButtonPushed, true);
            app.testonButton.Text = 'test on';
            app.testoffButton = uibutton(app.SubBurstsPanel, 'push');
            app.testoffButton.ButtonPushedFcn = createCallbackFcn(app, @testoffButtonPushed, true);
            app.testoffButton.Text = 'test off';
            app.ApRESPanel = uipanel(app.RadarTab);
            app.ApRESPanel.Title = 'ApRES';
            app.ApRESSwitch = uiswitch(app.ApRESPanel, 'toggle');
            app.ApRESSwitch.ValueChangedFcn = createCallbackFcn(app, @ApRESSwitchValueChanged, true);
            app.DownloadtoARKCheckBox = uicheckbox(app.RadarTab);
            app.DownloadtoARKCheckBox.Text = 'Download to ARK';
            app.FileNameOptionalTimeandLocationDATwillbeaddedtothisnamePanel = uipanel(app.RadarTab);
            app.FileNameOptionalTimeandLocationDATwillbeaddedtothisnamePanel.Title = 'PreFix (Optional) --> This Name + Time,Location,AntennaPolarization.DAT (Press Set)';
            app.ApRESPreFixName = uieditfield(app.FileNameOptionalTimeandLocationDATwillbeaddedtothisnamePanel, 'text');
            app.SendCMDBox = uieditfield(app.UIFigure, 'text');
            app.SendCMDBox.ValueChangedFcn = createCallbackFcn(app, @SendCMDBoxValueChanged, true);
            app.SendCMDButton = uibutton(app.UIFigure, 'push');
            app.SendCMDButton.ButtonPushedFcn = createCallbackFcn(app, @SendCMDButtonPushed, true);
            app.SendCMDButton.Enable = 'off';
            app.SendCMDButton.Text = 'SEND';
            app.RadioStreamBox = uitextarea(app.UIFigure);
            app.RadioStreamBox.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SubZero_ROS

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end